classdef shivaUNIX_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        File                  matlab.ui.container.Menu
        OpenMenuItem          matlab.ui.container.Menu
        OpenMenuItem2         matlab.ui.container.Menu
        CloseMenuItem         matlab.ui.container.Menu
        save                  matlab.ui.container.Menu
        write                 matlab.ui.container.Menu
        Load                  matlab.ui.container.Menu
        binary                matlab.ui.container.Menu
        saveRED               matlab.ui.container.Menu
        Interactive           matlab.ui.container.Menu
        Figure                matlab.ui.container.Menu
        print                 matlab.ui.container.Menu
        axes1                 matlab.ui.control.UIAxes
        axes2                 matlab.ui.control.UIAxes
        axes3                 matlab.ui.control.UIAxes
        axes4                 matlab.ui.control.UIAxes
        axes5                 matlab.ui.control.UIAxes
        axes6                 matlab.ui.control.UIAxes
        zoom                  matlab.ui.control.StateButton
        pan                   matlab.ui.control.StateButton
        edit1                 matlab.ui.control.EditField
        edit2                 matlab.ui.control.EditField
        edit3                 matlab.ui.control.EditField
        XLab                  matlab.ui.control.ListBox
        offset                matlab.ui.control.Button
        trigger               matlab.ui.control.Button
        decimate              matlab.ui.control.EditField
        smooth                matlab.ui.control.EditField
        cut_dt                matlab.ui.control.EditField
        cut                   matlab.ui.control.Button
        fft                   matlab.ui.control.Button
        Rint                  matlab.ui.control.EditField
        Rext                  matlab.ui.control.EditField
        Gefran                matlab.ui.control.CheckBox
        running               matlab.ui.control.Button
        fluid                 matlab.ui.control.CheckBox
        outlayers             matlab.ui.control.Button
        GH                    matlab.ui.control.CheckBox
        TC                    matlab.ui.control.CheckBox
        calibration           matlab.ui.control.DropDown
        T0                    matlab.ui.control.EditField
        AdjRate               matlab.ui.control.CheckBox
        text5                 matlab.ui.control.Label
        text6                 matlab.ui.control.Label
        Torque                matlab.ui.control.CheckBox
        off_enc_0             matlab.ui.control.Button
        incremental           matlab.ui.control.Button
        vac                   matlab.ui.control.CheckBox
        get_thickness         matlab.ui.control.CheckBox
        zero_thickness_long   matlab.ui.control.EditField
        popupAI6              matlab.ui.control.DropDown
        popupAI7              matlab.ui.control.DropDown
        popupAI8              matlab.ui.control.DropDown
        popupAI9              matlab.ui.control.DropDown
        popupAI10             matlab.ui.control.DropDown
        popupAI16             matlab.ui.control.DropDown
        popupAI17             matlab.ui.control.DropDown
        popupPF               matlab.ui.control.DropDown
        popupAI18             matlab.ui.control.DropDown
        popupPC               matlab.ui.control.DropDown
        uipanel1              matlab.ui.container.Panel
        nodeEnc2              matlab.ui.control.EditField
        nodeEnc1              matlab.ui.control.EditField
        text7                 matlab.ui.control.Label
        text8                 matlab.ui.control.Label
        text13                matlab.ui.control.Label
        pushbutton18          matlab.ui.control.Button
        pushbutton19          matlab.ui.control.Button
        pushbutton20          matlab.ui.control.Button
        brutalfilt            matlab.ui.control.EditField
        zero_thickness_short  matlab.ui.control.EditField
        text14                matlab.ui.control.Label
        text15                matlab.ui.control.Label
        refresh_thickness     matlab.ui.control.Button
    end

    
    methods (Access = private)
        function MaxEnc_Callback(app, hObject, eventdata, handles)
            % hObject    handle to MaxEnc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of MaxEnc as text
            %        str2double(get(hObject,'String')) returns contents of MaxEnc as a double
        end
        
        function MaxEnc_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to MaxEnc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function PrintMenuItem_Callback(app, hObject, eventdata, handles)
            % hObject    handle to PrintMenuItem (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            printdlg(app.figure1)
        end
        
        function TT_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to TT (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit1LB_Callback(app, hObject, eventdata, handles)
            % hObject    handle to edit1LB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns edit1LB contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from edit1LB
            
            
            s=get(hObject,'Value');
            h=findobj('Tag','edit1');
            app.g1=s;
            set(h,'String',s);
            
            guidata(hObject, handles);
            
            plotta_ora(handles)
        end
        
        function edit1LB_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit1LB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit2LB_Callback(app, hObject, eventdata, handles)
            % hObject    handle to edit2LB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns edit2LB contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from edit2LB
            
            s=get(hObject,'Value');
            h=findobj('Tag','edit2');
            app.g2=s;
            set(h,'String',s);
            
            guidata(hObject, handles);
            
            plotta_ora(handles)
        end
        
        function edit2LB_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit2LB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit3LB_Callback(app, hObject, eventdata, handles)
            % hObject    handle to edit3LB (see GCBO)
            %
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns edit3LB contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from edit3LB
            
            s=get(hObject,'Value');
            h=findobj('Tag','edit3');
            app.g3=s;
            set(h,'String',s);
            
            guidata(hObject, handles);
            
            plotta_ora(handles)
        end
        
        function edit3LB_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit3LB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function get_thickness_short_Callback(app, hObject, eventdata, handles)
            % hObject    handle to get_thickness_short (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of get_thickness_short
        end
        
        function plotta_ora(app, handles)
            
            hOb=findobj('Tag','XLab');
            h_ele=get(hOb,'Value');
            
            if h_ele==2
                app.X=app.Time/1000;
            elseif h_ele==3;
                if any(strcmp(fieldnames(handles),'slip'))
                    app.X=app.slip;
                elseif any(strcmp(fieldnames(handles),'Slip_Enc_2'))
                    app.X=app.Slip_Enc_2;
                end
            elseif h_ele==1
                app.X=app.Rate;
            end


            stato=app.zoom;
            posx=get(app.axes1,'XLim');
            
            eval(['plot(app.X,app.' app.column{(app.g1)} ',''ob'',''parent'',app.axes1);']);
            legend(app.axes1,[app.column{app.g1}])
            lim1=get(app.axes1,'Ylim'); a=findobj('Tag','lim1S'); set(a,'String',lim1(:,2)); b=findobj('Tag','lim1I'); set(b,'String',lim1(:,1));
            if (stato==1); set(app.axes1,'XLim',[posx]); end
            
            eval(['plot(app.X,app.' app.column{(app.g2)} ',''ob'',''parent'',app.axes2);']);
            legend(app.axes2,[app.column{app.g2}])
            lim2=get(app.axes2,'Ylim'); a=findobj('Tag','lim2S'); set(a,'String',lim2(:,2)); b=findobj('Tag','lim2I'); set(b,'String',lim2(:,1));
            if (stato==1); set(app.axes2,'XLim',[posx]); end
            
            eval(['plot(app.X,app.' app.column{(app.g3)} ',''ob'',''parent'',app.axes3);']);
            legend(app.axes3,[app.column{app.g3}])
            lim3=get(app.axes3,'Ylim'); a=findobj('Tag','lim3S'); set(a,'String',lim3(:,2)); b=findobj('Tag','lim3I'); set(b,'String',lim3(:,1));
            if (stato ==1) ; set(app.axes3,'XLim',[posx]); end
        end
        
        function popupmenu1_Callback(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
        end
        
        function popupmenu1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
            
            set(hObject, 'String', {'plot(rand(5))', 'plot(sin(1:0.01:25))', 'bar(1:.5:10)', 'plot(membrane)', 'surf(peaks)'});
        end
        
        function pushbutton1_Callback(app, hObject, eventdata, handles)
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            axes(app.axes1);
            cla;
            
            popup_sel_index = get(app.popupmenu1, 'Value');
            switch popup_sel_index
                case 1
                    plot(rand(5));
                case 2
                    plot(sin(1:0.01:25.99));
                case 3
                    bar(1:.5:10);
                case 4
                    plot(membrane);
                case 5
                    surf(peaks);
            end
        end
        
        function slipON_Callback(app, hObject, eventdata, handles)
            % hObject    handle to slipON (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of slipON
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function shivaUNIX_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to shivaUNIX (see VARARGIN)
%             % Function to maximize the window via undocumented Java call.
%             % Reference: http://undocumentedmatlab.com/blog/minimize-maximize-figure-window
%             
%             set(app.figure1, 'units', 'normalized', 'position', [0.01 0.01 0.9 0.9])
%             
%             axes(app.axes6)
%             matlabImage = imread('shiva.jpg');
%             image(matlabImage)
%             axis off
%             axis image
%             
%             % Choose default command line output for shivaUNIX
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
%             
%             % This sets up the initial plot - only do when we are invisible
%             % so window can get raised using shivaUNIX.
%             
%             % UIWAIT makes shivaUNIX wait for user response (see UIRESUME)
%             % uiwait(app.figure1);
        end

        % Value changed function: AdjRate
        function AdjRate_Callback(app, event)
            % hObject    handle to AdjRate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of AdjRate
        end

        % Menu selected function: CloseMenuItem
        function CloseMenuItem_Callback(app, event)
            % hObject    handle to CloseMenuItem (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            selection = questdlg(['Close ' get(app.figure1,'Name') '?'],...
                ['Close ' get(app.figure1,'Name') '...'],...
                'Yes','No','Yes');
            if strcmp(selection,'No')
                return;
            end
            
            delete(app.figure1)
        end

        % Value changed function: GH
        function GH_Callback(app, event)
            % hObject    handle to GH (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of GH
        end

        % Value changed function: Gefran
        function Gefran_Callback(app, event)
            % hObject    handle to Gefran (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of Gefran
            stato=get(hObject,'Value');
            
            if stato==1
                ButtonName = questdlg('GEFRAN preliminary operations: (1) identify the main sampling rate with cut_dt (2) decimate the data to 1s', ...
                    'Do you want to proceed now?', 'No', 'Yes','No');
            
                switch ButtonName,
                    case 'No',
                        disp('no');
                        set(hObject,'Value',0);
                    case 'Yes',
            
                        I=find(~strcmp(app.column,'SpeedGEF')); app.column=app.column(I);
                        I=find(~strcmp(app.column,'timeGEF')); app.column=app.column(I);
                        I=find(~strcmp(app.column,'TorqueGEF')); app.column=app.column(I);
            
                        Path2='/media/disk1/shivadir/Shiva Experiments';
                        name=app.filename(1:4);
            
                        list=dir([Path2 '/' name '*']);
                        if ~isempty(list); Path2=[Path2 '/' list.name '/'];
                            name2=dir([Path2 '*.txt']);
                            FileGEF=name2.name;
                        else
                            [FileGEF,Path2] = uigetfile( '/media/disk1/shivadir/*.txt', ...
                                'Multiple File Detected: Select the file GEFRAN to load');
                            name2='';
                        end
            
            
                        gefran1=1; k=0 ;
                        while ~isstruct(gefran1)
                            k=k+1;
                            gefran1=importdata([Path2 '/' FileGEF],'\t',k);
                        end
            
                        I=find(strncmp(gefran1.textdata,'Time	Speed',6)); if ~isempty(I); new.timeGEF=gefran1.data(:,1); new.VGEF(:,1)=gefran1.data(:,2); end
                        I=find(strncmp(gefran1.textdata,'Act Torque',6)); if ~isempty(I); new.TqGEF(:,1)=gefran1.data(:,I(1)-1); end
                        I=find(strncmp(gefran1.textdata,'Speed',5)); if ~isempty(I); new.VGEF(:,1)=gefran1.data(:,I(1)-1); end
                        I=find(strncmp(gefran1.textdata,'time',4)); if ~isempty(I); new.timeGEF(:,1)=gefran1.data(:,I(1)-1); end
            
                        dt=diff(new.timeGEF); dt(end+1)=dt(1);
            
                        %for j=1:length(app.column);
                        %    eval(['new.' app.column{j} '= downsample(app.' app.column{j} ',' num2str(25) ');'])
                        %end
                        %dati di calibrazione
            
                        nomi=[];
                        [a,b]=size(app.column); [aa,bb]=size(fieldnames(new));
                        inp1=app.column;
                        if aa==1 & aa==b | bb==1 & a==1; inp1=app.column'; end
                        nomi=[inp1 ; fieldnames(new)];
            
                        app.column=[];
                        app.column=nomi';
                        nomi2=fieldnames(new);
            
                        for k=1:length(nomi2)
                            eval(['app.' char(nomi2(k)) '=new.' char(nomi2(k)) ';'])
                        end
            
                        guidata(hObject, handles);
            
                end
            end % switch
        end

        % Menu selected function: Interactive
        function Interactive_Callback(app, event)
            % hObject    handle to Interactive (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            keyboard
            return
        end

        % Menu selected function: Load
        function Load_Callback(app, event)
            % hObject    handle to Load (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            ah_=get(app.axes1,'children'); set(ah_,'XData',[],'YData',[]);
            ah_=get(app.axes2,'children'); set(ah_,'XData',[],'YData',[]);
            ah_=get(app.axes3,'children'); set(ah_,'XData',[],'YData',[]);
            
            I=strcmp(fieldnames(handles),'column');
            
            if any(I)
                for i=1:length(app.column)
                    eval(['handles=rmfield(handles,''', app.column{i}, ''');'])
                end
                handles=rmfield(handles,'column');
            
            end
            clear file1
            I=strcmp(fieldnames(handles),'new'); if any(I); handles=rmfield(handles,'new'); end
            I=strcmp(fieldnames(handles),'X'); if any(I); handles=rmfield(handles,'X'); end
            I=strcmp(fieldnames(handles),'TimeZero'); if any(I); handles=rmfield(handles,'TimeZero'); end


            %definisce i grafici da plottare:
            %qui ci sono i default
            app.g1=2;
            app.g2=3;
            app.g3=5;
            
            ax_=findobj('Tag','edit1'); set(ax_,'String',app.g1);
            ax_=findobj('Tag','edit2'); set(ax_,'String',app.g2);
            ax_=findobj('Tag','edit3'); set(ax_,'String',app.g3);
            
            h_=findobj('Tag','edit1LB'); set(h_,'String',1);
            h_=findobj('Tag','edit2LB'); set(h_,'String',1);
            h_=findobj('Tag','edit3LB'); set(h_,'String',1);


            [FileName,PathName] = uigetfile('*.*','All Files (*.*)', ...
                'C:\Users\Stefano\Dropbox\Ricerca\SHIVA');
            
            cd (PathName)
            
            data=load(FileName);
            
            
            dataName=fieldnames(data);
            % if length(dataName); data=getfield(data,dataName{1}); end
            
            
            h_=findobj('Tag','dt_value');
            stato=get(h_,'Value');
            if isempty(stato)
                app.dt=0.04;
            else
                app.dt=stato;
            end
            
            
            %set(h_,'String',app.dt);
            
            app.filename=FileName;
            
            app.sm=0;
            app.triggered=0;
            app.cutted=[0 0];
            app.loadT=0;
            app.shearT=0;
            ll=1;
            nn=length(data.Time);
            
            app.column=fieldnames(data)
            
            for i=1:length(app.column)
                app.(app.column{i})=data.(app.column{i}); %debuggato
                %     eval(['app.' app.column{i} '=data.' app.column{i} ';'])
            end
            
            
            h_=findobj('Tag','edit1LB'); set(h_,'String',app.column);
            h_=findobj('Tag','edit2LB'); set(h_,'String',app.column);
            h_=findobj('Tag','edit3LB'); set(h_,'String',app.column);
            
            % Assess if time is milliseconds or not (this fixes a bug when calculating velocity)
            
            timess = app.Stamp;
            if max(timess)>60 || min(timess)>0.7
                disp('Time is in Milliseconds')
                app.tconv = 1;
            elseif max(timess)<60 || min(timess)<0.7
                disp('Time is in seconds')
                app.tconv = 1000;
            else
                disp('Unable to ascertain time units')
            end
            
            app.load=1;
            app.Done=1;
            new=handles;
            guidata(hObject, handles);
            guidata(hObject, new);
            
            app.zoom=0;


            plotta_ora(handles);
        end

        % Menu selected function: OpenMenuItem2
        function OpenMenuItem2_Callback(app, event)
            % hObject    handle to OpenMenuItem2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %ripulisci precedente
            
            ah_=get(app.axes1,'children'); set(ah_,'XData',[],'YData',[]);
            ah_=get(app.axes2,'children'); set(ah_,'XData',[],'YData',[]);
            ah_=get(app.axes3,'children'); set(ah_,'XData',[],'YData',[]);
            
            I=strcmp(fieldnames(handles),'column');
            
            if any(I)
                for i=1:length(app.column)
                    eval(['handles=rmfield(handles,''', app.column{i}, ''');'])
                end
                handles=rmfield(handles,'column');
            
            end
            
            fname=fieldnames(handles);
            I=strfind(fname,'GEF');
            if any(cell2mat(I))
                for i=1:length(fname)
                    if ~isempty(strfind(fname{i},'GEF'));
                        eval(['handles=rmfield(handles,''', fname{i}, ''');'])
                    end
                end
            end
            
            
            app.load=0; % flag su load o open
            clear file1
            I=strcmp(fieldnames(handles),'new'); if any(I); handles=rmfield(handles,'new'); end
            I=strcmp(fieldnames(handles),'X'); if any(I); handles=rmfield(handles,'X'); end
            I=strcmp(fieldnames(handles),'TimeZero'); if any(I); handles=rmfield(handles,'TimeZero'); end


            %definisce i grafici da plottare:
            %qui ci sono i default
            app.g1=2;
            app.g2=3;
            app.g3=5;
            
            ax_=findobj('Tag','edit1'); set(ax_,'String',app.g1);
            ax_=findobj('Tag','edit2'); set(ax_,'String',app.g2);
            ax_=findobj('Tag','edit3'); set(ax_,'String',app.g3);
            
            [FileName,PathName] = uigetfile('*.*','All Files (*.*)', ...
                '~/Documents/Roma/Raw lab data');
            
            
            cd (PathName)
            
            %definisce i parametri da matrice
            %app.column=importdata(FileName,'\t',1);
            
            fid=fopen(FileName,'r');
            for i=1:3
                file1=fgets(fid);
            end
            fclose(fid);
            
            
            %file0=importdata(FileName,'\t',3);file1=char(file0(3,:));
            [I]=find(file1==char(44)); change=false;
            if ~isempty(I); file1(I)=char(46); change=true; end
            A=sscanf(file1,'%f');
            b=length(A); clear A
            fid=fopen(FileName,'r');
            
            for i=1:b
                A=fscanf(fid,'%s',1);
                %controlla che non interpreti uno spazio come nuova variabile
                if any(strcmp(fieldnames(handles),'column')) && ...
                        strcmp(A,2); app.column{i-1}={[char(app.column(i-1)), '2']};
                else
                    app.column(i)={A};
                end
                %controlla che non ce ne siano due uguali
                S=sum(strcmp(app.column(i), app.column));
                if S > 1; app.column{i}=([char(app.column(i)), '2']); end
            
            end
            
            fgets(fid);    fgets(fid); i=0;
            if change
                while 1
                    i=i+1;
                    tline = fgetl(fid);
                    if ~ischar(tline); break; end
                    [I]=find(tline==char(44));
                    if ~isempty(I); tline(I)=char(46); end
                    file1.data(i,:)=sscanf(tline,'%f');
                end
            else
                file1=importdata(FileName,'\t',3);
            end
            fclose(fid);
            
            h_=findobj('Tag','dt_value');
            
            %[ndt,vdt]=grp2idx(file1.data(:,1));
            %if numel(vdt) > 1; app.dt=str2double(vdt(2));
            %else
            %    app.dt=str2double(vdt(1))
            %end
            
            
            %set(h_,'String',app.dt);
            
            app.filename=FileName;
            
            app.sm=0;
            app.triggered=0;
            app.cutted=[0 0];
            app.loadT=0;
            app.shearT=0;
            ll=1;
            nn=length(file1.data(:,1));
            timess = file1.data(:,1);
            if max(timess)>60 || min(timess)>0.7
                disp('Time is in Milliseconds')
                tconv = 1;
            elseif max(timess)<60 || min(timess)<0.7
                disp('Time is in seconds')
                tconv = 1000;
            else
                disp('Unable to ascertain time units')
            end
            %primo step:togliere tutto quello che ha un campionamento diverso da dt
            %app.xlab=0:app.dt:(length(file1.data)-1)*app.dt;
            %memorizza anche gli originali
            %eval(['app.' app.column{1} ' = cumsum(file1.data(ll:nn,1)); '])
            %eval(['app.v' num2str(1) ' = cumsum(file1.data(ll:nn,1)); '])
            
            
            app.column{1}='Time';
            num=length(app.column);
            
            for n=2:num
                test=double(app.column{n});
                if any(test==32)
                    app.column{n}=char(test(test~=32));
                end
                eval(strcat('app.',app.column{n}, '= file1.data(ll:nn,', num2str(n), ');'))
            end


            app.column{num+1}='Stamp';
            eval(['app.' app.column{num+1} '= file1.data(ll:nn,1);'])
            
            num=length(app.column);
            app.column{num+1}='Rate';
            eval(['app.' app.column{num+1} '= [1:1:length(file1.data(ll:nn,1))]''; '])
            
            num=length(app.column);
            app.column{num+1}='RateZero';
            eval(['app.' app.column{num+1} '= [1:1:length(file1.data(ll:nn,1))]''; '])
            
            % --> ele
            hv=get(app.XLab(1),'Value');
            app.TimeZero=cumsum(app.Stamp);
            app.Time=zeros(size(app.Stamp));
            app.Time(1)=hv*app.Stamp(1);
            app.Time(2:end)=hv*app.Stamp(1) +cumsum(app.Stamp(2:end)); %plotto il numero di riga
            
            app.Done=[];
            app.Time=app.Time*tconv;
            app.tconv=tconv;
            
            guidata(hObject, handles);
            app.zoom=0;
            
            plotta_ora(handles);
        end

        % Menu selected function: OpenMenuItem
        function OpenMenuItem_Callback(app, event)
            % hObject    handle to OpenMenuItem2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %ripulisci precedente
            
            ah_=get(app.axes1,'children'); set(ah_,'XData',[],'YData',[]);
            ah_=get(app.axes2,'children'); set(ah_,'XData',[],'YData',[]);
            ah_=get(app.axes3,'children'); set(ah_,'XData',[],'YData',[]);
            
            I=strcmp(fieldnames(handles),'column');
            
            if any(I)
                for i=1:length(app.column)
                    eval(['handles=rmfield(handles,''', app.column{i}, ''');'])
                end
                handles=rmfield(handles,'column');
            
            end
            
            fname=fieldnames(handles);
            I=strfind(fname,'GEF');
            if any(cell2mat(I))
                for i=1:length(fname)
                    if ~isempty(strfind(fname{i},'GEF'));
                        eval(['handles=rmfield(handles,''', fname{i}, ''');'])
                    end
                end
            end
            
            
            app.load=0; % flag su load o open
            clear file1
            I=strcmp(fieldnames(handles),'new'); if any(I); handles=rmfield(handles,'new'); end
            I=strcmp(fieldnames(handles),'X'); if any(I); handles=rmfield(handles,'X'); end
            I=strcmp(fieldnames(handles),'TimeZero'); if any(I); handles=rmfield(handles,'TimeZero'); end


            %definisce i grafici da plottare:
            %qui ci sono i default
            app.g1=2;
            app.g2=3;
            app.g3=5;
            
            ax_=findobj('Tag','edit1'); set(ax_,'String',app.g1);
            ax_=findobj('Tag','edit2'); set(ax_,'String',app.g2);
            ax_=findobj('Tag','edit3'); set(ax_,'String',app.g3);
            
            [FileName,PathName] = uigetfile('*.*','All Files (*.*)', ...
                'C:\Users\stear\Dropbox\Ricerca\SHIVA');
            
            
            cd (PathName)
            
            %definisce i parametri da matrice
            %app.column=importdata(FileName,'\t',1);
            
            fid=fopen(FileName,'r');
            for i=1:3
                file1=fgets(fid);
            end
            fclose(fid);
            
            
            %file0=importdata(FileName,'\t',3);file1=char(file0(3,:));
            [I]=find(file1==char(44)); change=logical(0);
            if ~isempty(I); file1(I)=char(46); change=logical(1); end
            A=sscanf(file1,'%f');
            b=length(A); clear A
            fid=fopen(FileName,'r');
            
            for i=1:b
                A=fscanf(fid,'%s',1);
                %controlla che non interpreti uno spazio come nuova variabile
                if any(strcmp(fieldnames(handles),'column')) && ...
                        strcmp(A,2); app.column{i-1}={[char(app.column(i-1)), '2']};
                else app.column(i)={A};
                end
                %controlla che non ce ne siano due uguali
                S=sum(strcmp(app.column(i), app.column));
                if S > 1; app.column{i}=([char(app.column(i)), '2']); end
            
            end
            
            fgets(fid);    fgets(fid); i=0;
            if change
                while 1
                    i=i+1;
                    tline = fgetl(fid);
                    if ~ischar(tline), break, end
                    [I]=find(tline==char(44));
                    if ~isempty(I); tline(I)=char(46); end
                    file1.data(i,:)=sscanf(tline,'%f');
                end
            else
                file1=importdata(FileName,'\t',3);
            end
            fclose(fid);
            
            h_=findobj('Tag','dt_value');
            
            %[ndt,vdt]=grp2idx(file1.data(:,1));
            %if numel(vdt) > 1; app.dt=str2double(vdt(2));
            %else
            %    app.dt=str2double(vdt(1))
            %end
            
            
            %set(h_,'String',app.dt);
            
            app.filename=FileName;
            
            app.sm=0;
            app.triggered=0;
            app.cutted=[0 0];
            app.loadT=0;
            app.shearT=0;
            ll=1;
            nn=length(file1.data(:,1));
            
            %primo step:togliere tutto quello che ha un campionamento diverso da dt
            %app.xlab=0:app.dt:(length(file1.data)-1)*app.dt;
            %memorizza anche gli originali
            %eval(['app.' app.column{1} ' = cumsum(file1.data(ll:nn,1)); '])
            %eval(['app.v' num2str(1) ' = cumsum(file1.data(ll:nn,1)); '])
            app.column{1}='Time';
            num=length(app.column);
            
            for n=2:num
                test=double(app.column{n});
                if any(test==32); app.column{n}=char(test(test~=32)); end
                eval(['app.' app.column{n} ' = file1.data(ll:nn,' num2str(n) ');'])
            end
            
            
            app.column{num+1}='Stamp';
            eval(['app.' app.column{num+1} '= file1.data(ll:nn,1); '])
            
            num=length(app.column);
            app.column{num+1}='Rate';
            eval(['app.' app.column{num+1} '= [1:1:length(file1.data(ll:nn,1))]''; '])
            
            
            num=length(app.column);
            app.column{num+1}='RateZero';
            eval(['app.' app.column{num+1} '= [1:1:length(file1.data(ll:nn,1))]''; '])
            
            % --> ele
            
            
            hv=get(app.XLab(1),'Value');
            app.TimeZero=cumsum(app.Stamp);
            app.Time=zeros(size(app.Stamp));
            app.Time(1)=hv*app.Stamp(1);
            app.Time(2:end)=hv*app.Stamp(1) +cumsum(app.Stamp(2:end)); %plotto il numero di riga
            
            app.Done=[];
            
            app.tconv = 1000; %<--- Corrections for time conversion in velocity calculation!
            
            guidata(hObject, handles);
            app.zoom=0;
            
            plotta_ora(handles);
        end

        % Value changed function: Rext
        function Rext_Callback(app, event)
            % hObject    handle to Rext (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Rext as text
            %        str2double(get(hObject,'String')) returns contents of Rext as a double
        end

        % Value changed function: T0
        function T0_Callback(app, event)
            % hObject    handle to TT (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of TT as text
            %        str2double(get(hObject,'String')) returns contents of TT as a double
        end

        % Value changed function: TC
        function TC_Callback(app, event)
            % hObject    handle to TC (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of TC
        end

        % Value changed function: Torque
        function Torque_Callback(app, event)
            % hObject    handle to Torque (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of Torque
        end

        % Value changed function: XLab
        function XLab_Callback(app, event)
            % hObject    handle to XLab (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % hObject    handle to XLab (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of XLab
            
            h_ele=get(hObject,'Value')
            
            if h_ele==2
                app.X=app.Time/1000;
            elseif h_ele==3;
                if any(strcmp(fieldnames(handles),'slip'))
                    app.X=app.slip;
                elseif any(strcmp(fieldnames(handles),'Slip_Enc_2'))
                    app.X=app.Slip_Enc_2;
                else disp('not existent field Slip'); set(hObject,'Value',1);
                end
            elseif h_ele==1
                app.X=app.Rate;
            end
            
            
            guidata(hObject, handles);
            
            plotta_ora(handles)
        end

        % Menu selected function: binary
        function binary_Callback(app, event)
            % hObject    handle to binary (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [nome,pat]=uiputfile( ...
                {'*.txt', 'All MATLAB Files (*txt)'; ...
                '*.*',                   'All Files (*.*)'}, ...
                'Write as',['~/',app.filename]);
            cd (pat)
            
            
            for j=1:length(app.column)
                C(j,1)={'%10.6f '};
                if j==length(app.column)
                    C(j,1)={'%10.6f\n'};
                end
            end
            C1=cell2mat(C');
            
            for j=1:length(app.column)
                N(j,1)={['app.' app.column{j} '(l,1),']};
                if j==length(app.column)
                    N(j,1)={['app.' app.column{j} '(l,1)']};
                end
            end
            N1=cell2mat(N');
            
            
            for j=1:length(app.column)
                M(j,1)={['''' app.column{j} '''' ',']};
                if j==length(app.column)
                    M(j,1)={['''' app.column{j} '''']};
                end
            end
            M1=cell2mat(M');
            
            %for j=1:length(app.column)
            %    O(j,1)={['''v' num2str(j) '''' ',']};
            %    if j==length(app.column)
            %         O(j,1)={['''v' num2str(j) '''']};
            %    end
            %end
            %O1=cell2mat(O');
            
            
            for j=1:length(app.column)
                S(j,1)={'%s '};
                if j==length(app.column)
                    S(j,1)={'%s\n'};
                end
            end
            S1=cell2mat(S');
            
            %write in a file
            nome2=[nome, 'RED.txt'];
            fid = fopen(nome2,'wt');
            eval(['fprintf(fid,''' S1 ''',' M1 ');'])
            
            eval(['len=length(app.' app.column{1} ');'])
            
            for l=1:len
                eval(['fprintf(fid,''' C1 ''',' N1 ');'])
            end
            fclose(fid);
            if ~ strcmp(fieldnames(handles),'dt'); msgbox(['ATTENTION: app.dt=none']); end
        end

        % Value changed function: brutalfilt
        function brutalfilt_Callback(app, event)
            % hObject    handle to brutalfilt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of brutalfilt as text
            %        str2double(get(hObject,'String')) returns contents of brutalfilt as a double
            
            
            set(hObject,'Enable','on','BackGroundColor','white')
            app.brut=str2num(get(hObject,'String'));
            
            if isempty(app.brut); app.brut=500; end
            ginput(1)
            ax_=get(gcf,'CurrentAxes');
            
            for n=1:3
                %     s=find(ax_==app.figure1.Children);
                s=find(ax_==app.(['axes' num2str(n)]));
            
                if s==1; break; end
            end
            
            
            % finestra=n;
            
            % posy=get(ax_,'Ylim');
            % posx=get(ax_,'Xlim');
            
            h_=findobj('Tag',['edit' num2str(n)]); %n=numero asse
            s=str2double(get(h_,'String'));  %numero della colonna
            
            % brutal filter & store in handles
            
            app.([app.column{s} 'o'])=app.(app.column{s});
            app.([app.column{s}])=brutal_filter_fx(1024,0.9,app.brut,app.(app.column{s}),app.Stamp);
            
            % store in column vector
            if ~strcmp(app.column,{[app.column{s} 'o']})
                app.column(end+1)={[app.column{s} 'o']};
            end
            
            h_=findobj('Tag','edit1LB'); set(h_,'String',app.column);
            h_=findobj('Tag','edit2LB'); set(h_,'String',app.column);
            h_=findobj('Tag','edit3LB'); set(h_,'String',app.column);
            
            guidata(hObject, handles);
            
            plotta_ora(handles);
        end

        % Button pushed function: cut
        function cut_Callback(app, event)
            % hObject    handle to cut (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            hOb=findobj('Tag','XLab');
            h_ele=get(hOb,'Value');
            
            if h_ele==2
                t_cut=app.Time/1000;
            elseif h_ele==3;
                if any(strcmp(fieldnames(handles),'slip'))
                    t_cut=app.slip;
                elseif any(strcmp(fieldnames(handles),'Slip_Enc_2'))
                    t_cut=app.Slip_Enc_2;
                end
            elseif h_ele==1
                t_cut=app.XLab;
            end
            
            %left to right clicking sequence on plot
            [xi,yi]=ginput(2) ;
            
            
            mat(1,:)=abs(t_cut-ones(size(t_cut))*xi(1));
            mat(2,:)=abs(t_cut-ones(size(t_cut))*xi(2));
            ll(:,1)=find(mat(1,:)==min(mat(1,:)));
            ll(:,2)=find(mat(2,:)==min(mat(2,:)));
            
            app.cutted(1,1)=app.RateZero(ll(1,1));
            app.cutted(1,2)=app.RateZero(ll(1,2));
            
            for n=1:length(app.column)
                eval(['app.' app.column{n} ' =app.' app.column{n} '(ll(:,1):ll(:,2),:);' ])
            end
            
            
            guidata(hObject, handles);
            plotta_ora(handles)
        end

        % Value changed function: cut_dt
        function cut_dt_Callback(app, event)
            % hObject    handle to cut_dt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of cut_dt as text
            %        str2double(get(hObject,'String')) returns contents of cut_dt as a double
            set(hObject,'Enable','on')
            
            
            app.dt=str2double(get(hObject,'String'));
            %set(hObject,'String',app.dt,'BackgroundColor',[0.75 0.75 0.75])
            ll=find(app.Stamp(:,1)==app.dt); %,1,'first');
            if length(ll) <= 100; h=msgbox('attention: number of residuals less than 100'); waitfor(h); return; end
            %nn=find(app.Stamp(:,1)==app.dt,1,'last');
            
            for n=1:length(app.column)
                eval(['app.' app.column{n} ' = app.' app.column{n} '(ll,1);'])
            end
            
            
            % ax_=get(app.axes1,'Children'); dataY=get(ax_,'YData'); dataX=get(ax_,'XData');
            % set(ax_,'XData',dataX(ll),'YData',dataY(ll)); set(app.axes1,'XLim',[dataX(ll(1)) dataX(ll(end))]);
            % ax_=get(app.axes2,'Children'); dataY=get(ax_,'YData'); dataX=get(ax_,'XData');
            % set(ax_,'XData',dataX(ll),'YData',dataY(ll)); set(app.axes2,'XLim',[dataX(ll(1)) dataX(ll(end))]);
            % ax_=get(app.axes3,'Children'); dataY=get(ax_,'YData'); dataX=get(ax_,'XData');
            % set(ax_,'XData',dataX(ll),'YData',dataY(ll)); set(app.axes3,'XLim',[dataX(ll(1)) dataX(ll(end))]);
            
            
            set(hObject,'String','cut_dt')
            guidata(hObject, handles);
            plotta_ora(handles)
        end

        % Value changed function: decimate
        function decimate_Callback(app, event)
            % hObject    handle to decimate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if ~any(strcmp(fieldnames(handles),'column')); err=msgbox('no data stored!'); waitfor(err); return; end
            
            set(hObject,'Enable','on','BackGroundColor','white')
            app.Ndec=str2num(get(hObject,'String'))
            %if any(strcmp(fieldnames(handles),'dec')); k=menu('decimate again?','yes','no');end
            %if k==1
            for n=1:length(app.column)
                eval(['app.' app.column{n} ' = downsample(app.' app.column{n} ',' num2str(app.Ndec) ');'])
            end
            
            %elseif k==2
            %    return
            %end
            
            app.dec='ok';
            
            guidata(hObject, handles);
            plotta_ora(handles);
        end

        % Value changed function: edit1
        function edit1_Callback(app, event)
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit1 as text
            %        str2double(get(hObject,'String')) returns contents of edit1 as a double
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
            
            app.g1=str2double(get(hObject,'String'));
            
            if app.g1 ~= app.g1
                [s,v] = listdlg('PromptString','Select a file:',...
                    'SelectionMode','single',...
                    'ListString',app.column);
            
                app.g1=s; %str2double(get(hObject,'String'));
            end
            
            guidata(hObject, handles);
            set(hObject,'String',app.g1)
            
            hOb=findobj('Tag','XLab');
            h_ele=get(hOb,'Value');
            
            if h_ele==2
                app.X=app.Time/1000;
            elseif h_ele==3;
                if any(strcmp(fieldnames(handles),'slip'))
                    app.X=app.slip;
                elseif any(strcmp(fieldnames(handles),'Slip_Enc_2'))
                    app.X=app.Slip_Enc_2;
                end
            elseif h_ele==1
                app.X=app.Rate;
            end
            
            
            eval(['plot(app.X,app.' app.column{(app.g1)} ',''ob'',''parent'',app.axes1);']);
            legend(app.axes1,[app.column{app.g1}])
        end

        % Value changed function: edit2
        function edit2_Callback(app, event)
            % hObject    handle to edit2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit2 as text
            %        str2double(get(hObject,'String')) returns contents of edit2 as a double
            app.g2=str2double(get(hObject,'String'));
            
            if app.g2 ~= app.g2
                [s,v] = listdlg('PromptString','Select a file:',...
                    'SelectionMode','single',...
                    'ListString',app.column);
            
                app.g2=s; %str2double(get(hObject,'String'));
            end
            guidata(hObject, handles);
            set(hObject,'String',app.g2)
            
            hOb=findobj('Tag','XLab');
            h_ele=get(hOb,'Value');
            
            if h_ele==2
                app.X=app.Time/1000;
            elseif h_ele==3;
                if any(strcmp(fieldnames(handles),'slip'))
                    app.X=app.slip;
                elseif any(strcmp(fieldnames(handles),'Slip_Enc_2'))
                    app.X=app.Slip_Enc_2;
                end
            elseif h_ele==1
                app.X=app.Rate;
            end
            
            
            eval(['plot(app.X,app.' app.column{(app.g2)} ',''ob'',''parent'',app.axes2);']);
            legend(app.axes2,[app.column{app.g2}])
        end

        % Value changed function: edit3
        function edit3_Callback(app, event)
            % hObject    handle to edit3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit3 as text
            %        str2double(get(hObject,'String')) returns contents of edit3 as a double
            % Hints: get(hObject,'String') returns contents of edit3 as text
            %        str2double(get(hObject,'String')) returns contents of edit3 as a double
            %app.g3=str2double(get(hObject,'String'));
            app.g3=str2double(get(hObject,'String'));
            
            if app.g3 ~= app.g3
                [s,v] = listdlg('PromptString','Select a file:',...
                    'SelectionMode','single',...
                    'ListString',app.column);
            
                app.g3=s; %str2double(get(hObject,'String'));
            end
            guidata(hObject, handles);
            set(hObject,'String',app.g3)
            
            
            hOb=findobj('Tag','XLab');
            h_ele=get(hOb,'Value');
            
            if h_ele==2
                app.X=app.Time/1000;
            elseif h_ele==3;
                if any(strcmp(fieldnames(handles),'slip'))
                    app.X=app.slip;
                elseif any(strcmp(fieldnames(handles),'Slip_Enc_2'))
                    app.X=app.Slip_Enc_2;
                end
            elseif h_ele==1
                app.X=app.Rate;
            end
            eval(['plot(app.X,app.' app.column{(app.g3)} ',''ob'',''parent'',app.axes3);']);
            legend(app.axes3,[app.column{app.g3}])
        end

        % Button pushed function: fft
        function fft_Callback(app, event)
            % hObject    handle to fft (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            hOb=findobj('Tag','XLab');
            h_ele=get(hOb,'Value');
            
            if h_ele==2
                t_cut=app.Time/1000;
            elseif h_ele==3;
                if any(strcmp(fieldnames(handles),'slip'))
                    t_cut=app.slip;
                elseif any(strcmp(fieldnames(handles),'Slip_Enc_2'))
                    t_cut=app.Slip_Enc_2;
                end
            elseif h_ele==1
                t_cut=app.XLab;
            end
            
            
            ax_=get(gcf,'CurrentAxes');
            
            %h_=findobj('Tag','dt_value');
            ginput(1)
            for n=1:3
                eval(['s=find(ax_==app.axes' num2str(n) ');'])
                if s==1; break; end
            end
            finestra=n;
            
            ax_=get(gcf,'CurrentAxes');
            posy=get(ax_,'Ylim');
            posx=get(ax_,'Xlim');
            I1=find(abs(t_cut-posx(1))==min(abs(t_cut-posx(1))));
            I2=find(abs(t_cut-posx(2))==min(abs(t_cut-posx(2))));
            
            eval(['h_=findobj(''Tag'',''edit' num2str(n) ''');']); %n=numero asse
            s=str2double(get(h_,'String'));  %numero della colonna
            
            
            eval(['mmed_torq=mean(smooth(app.' app.column{s} '(1:100,:)));']);
            eval(['app.' app.column{s} '=app.' app.column{s} '- mmed_torq;']);
            
            %tapering
            
            eval(['app.' app.column{s} '=app.' app.column{s} '(I1:I2).*hamming(length(app.Stamp(I1:I2)));'])
            dt=mode(app.Stamp(I1:I2)); %str2double(get(h_,'String'));
            
            nfft=4096;
            eval(['Y =fft(app.' app.column{s} ',' num2str(nfft) ');'])
            f = 1000/dt*(0:nfft/2)/nfft;
            Pyy = Y.* conj(Y) / nfft;
            
            plot(f,Pyy(1:nfft/2+1),'Parent',app.axes4);
            set(app.axes4,'XLim',[0 250])
            
            plot(1./f*1000/dt,Pyy(1:nfft/2+1),'Parent',app.axes5);
            set(app.axes5,'XLim',[0 500])
            
            set(hObject,'Value',0)
        end

        % Value changed function: fluid
        function fluid_Callback(app, event)
            % hObject    handle to fluid (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of fluid
        end

        % Value changed function: get_thickness
        function get_thickness_Callback(app, event)
            % hObject    handle to get_thickness (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of get_thickness
        end

        % Value changed function: nodeEnc1
        function nodeEnc1_Callback(app, event)
            % hObject    handle to nodeEnc1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of nodeEnc1 as text
            %        str2double(get(hObject,'String')) returns contents of nodeEnc1 as a double
        end

        % Value changed function: nodeEnc2
        function nodeEnc2_Callback(app, event)
            % hObject    handle to nodeEnc2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of nodeEnc2 as text
            %        str2double(get(hObject,'String')) returns contents of nodeEnc2 as a double
        end

        % Button pushed function: off_enc_0
        function off_enc_0_Callback(app, event)
            % hObject    handle to off_enc_0 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            I=app.triggered;
            app.Encoder2(1:I)=0;
            app.Encoder(1:I)=0;
            guidata(hObject, handles);
        end

        % Button pushed function: offset
        function offset_Callback(app, event)
            % hObject    handle to offset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % hObject    handle to offset_1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [sel,v] = listdlg('PromptString','Select a file:',...
                'SelectionMode','multiple',...
                'ListString',app.column);
            
            h_=app.column(sel);
            hOb=findobj('Tag','XLab');
            h_ele=get(hOb,'Value');
            htype=get(hOb,'String');
            
            if  strcmp(htype{h_ele},'Time (s)')
                t_cut=app.Time/1000;
            elseif  strcmp(htype{h_ele},'Slip (m)')
                if any(strcmp(fieldnames(handles),'slip'))
                    t_cut=app.slip;
                elseif any(strcmp(fieldnames(handles),'Slip_Enc_2'))
                    t_cut=app.Slip_Enc_2;
                end
            elseif  strcmp(htype{h_ele},'Rate (#)')
            
                t_cut=app.Rate;
            end


            [xi,yi]=ginput(1) ;
            mat(1,:)=abs(t_cut-ones(size(t_cut))*xi(1));
            ll(:,1)=find(mat(1,:)==min(mat(1,:)));
            
            ax_=get(gcf,'CurrentAxes');
            for n=1:3
                eval(['s=find(ax_==app.axes' num2str(n) ');'])
                if s==1; break; end
            end
            posy=get(ax_,'Ylim');
            posx=get(ax_,'Xlim');
            
            eval(['h_=findobj(''Tag'',''edit' num2str(n) ''');']); %n=numero asse
            colonna=str2double(get(h_,'String'));                        %numero della colonna
            
            for n=sel
                eval(['app.' app.column{n} ' =app.' app.column{n} '-app.' app.column{n} '(ll(:,1),:);'])
                %eval(['app.' app.column{n} '(1:ll(:,1),:)=0;'])
            end
            
            nsel=find(strcmp(app.column(sel),'Axial'));
            if isempty(nsel)
                app.shearT=app.RateZero(ll);
            else
                app.loadT=app.RateZero(ll);
            end
            
            h_=findobj('Tag','edit1');
            s1=str2double(get(h_,'String'));
            h_=findobj('Tag','edit2');
            s2=str2double(get(h_,'String'));
            h_=findobj('Tag','edit3');
            s3=str2double(get(h_,'String'));
            
            guidata(hObject, handles);
            plotta_ora(handles)
        end

        % Button pushed function: outlayers
        function outlayers_Callback(app, event)
            % hObject    handle to outlayers (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %zoom xon;
            hOb=findobj('Tag','XLab');
            h_ele=get(hOb,'Value');
            
            if h_ele==2
                t_cut=app.Time/1000;
            elseif h_ele==3;
                if any(strcmp(fieldnames(handles),'slip'))
                    t_cut=app.slip;
                elseif any(strcmp(fieldnames(handles),'Slip_Enc_2'))
                    t_cut=app.Slip_Enc_2;
                end
            elseif h_ele==1
                t_cut=app.XLab;
            end
            
            button=1; i=0;
            while button==1
                i=i+1
                [xi(i),yi,button]=ginput(1) ;
                button
            end
            
            
            ax_=get(gcf,'CurrentAxes');
            for n=1:3
                eval(['s=find(ax_==app.axes' num2str(n) ');'])
                if s==1; break; end
            end
            finestra=n;
            posy=get(ax_,'Ylim');
            posx=get(ax_,'Xlim');
            
            eval(['h_=findobj(''Tag'',''edit' num2str(n) ''');']); %n=numero asse
            s=str2double(get(h_,'String'));  %numero della colonna
            
            
            for i=1:length(xi);
                mat(1,:)=abs(t_cut-ones(size(t_cut))*xi(i));
                ll(i)=find(mat(1,:)==min(mat(1,:)),1,'first');
            end
            
            if button==3
            
                for i=1:length(xi);
                    %running mean
            
                    if ll==1; eval(['app.' app.column{s} '(ll(i))=app.' app.column{s} '(ll(i)+1);']);
                    else
                        eval(['app.' app.column{s} '(ll(i))=app.' app.column{s} '(ll(i)-1);']);
                    end
            
                end
            
            
            elseif button==2
                eval(['app.' app.column{s} '(ll(1):ll(end))=app.' app.column{s} '(ll(1)-1);']);
            
            end %if button
            
            
            guidata(hObject, handles);
            
            plotta_ora(handles)
        end

        % Value changed function: pan
        function pan_Callback(app, event)
            % hObject    handle to pan (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h_ele=get(hObject,'Value');
            pippo=[app.axes1, app.axes2,app.axes3];
            linkaxes(pippo,'x');
            
            if h_ele==1
                pan on;
            else
                pan off
            end
        end

        % Value changed function: popupAI10
        function popupAI10_Callback(app, event)
            % hObject    handle to popupAI10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupAI10 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupAI10
        end

        % Value changed function: popupAI16
        function popupAI16_Callback(app, event)
            % hObject    handle to popupAI16 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupAI16 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupAI16
        end

        % Value changed function: popupAI17
        function popupAI17_Callback(app, event)
            % hObject    handle to popupAI17 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupAI17 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupAI17
        end

        % Value changed function: popupAI18
        function popupAI18_Callback(app, event)
            % hObject    handle to popupAI18 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupAI18 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupAI18
        end

        % Value changed function: popupAI6
        function popupAI6_Callback(app, event)
            % hObject    handle to popupAI6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupAI6 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupAI6
        end

        % Value changed function: popupAI7
        function popupAI7_Callback(app, event)
            % hObject    handle to popupAI7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupAI7 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupAI7
        end

        % Value changed function: popupAI8
        function popupAI8_Callback(app, event)
            % hObject    handle to popupAI8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupAI8 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupAI8
        end

        % Value changed function: popupAI9
        function popupAI9_Callback(app, event)
            % hObject    handle to popupAI9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupAI9 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupAI9
        end

        % Value changed function: popupPC
        function popupPC_Callback(app, event)
            % hObject    handle to popupPC (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupPC contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupPC
        end

        % Value changed function: popupPF
        function popupPF_Callback(app, event)
            % hObject    handle to popupPF (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupPF contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupPF
        end

        % Menu selected function: print
        function print_Callback(app, event)
            % hObject    handle to file (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            hf=figure;
            hn_=copyobj(app.axes1,hf); h1=get(hn_,'Position'); h1(1)=h1(1)+0.1; set(hn_,'Position',h1);
            ah_=get(app.axes1,'children'); nom=get(ah_,'DisplayName');
            hnl_=get(hn_,'YLabel'); set(hnl_,'string',nom)
            
            hn_=copyobj(app.axes2,hf); h1=get(hn_,'Position'); h1(1)=h1(1)+0.1; set(hn_,'Position',h1);
            ah_=get(app.axes2,'children'); nom=get(ah_,'DisplayName');
            hnl_=get(hn_,'YLabel'); set(hnl_,'string',nom)
            
            hn_=copyobj(app.axes3,hf); h1=get(hn_,'Position'); h1(1)=h1(1)+0.1; set(hn_,'Position',h1);
            ah_=get(app.axes3,'children'); nom=get(ah_,'DisplayName');
            hnl_=get(hn_,'YLabel'); set(hnl_,'string',nom)
        end

        % Button pushed function: pushbutton18
        function pushbutton18_Callback(app, event)
            disp('Refresh temperature!')
            
            AIstate=zeros(1,18);
            for L=1:18
                obj=num2str(L); obj=strcat('popupAI',obj);
                if isempty(findobj('Tag',obj))
                else
                    h=findobj('Tag',obj);
                    AIstate(1,L)=get(h,'Value');
                end
            end
            
            for L=1:18
                b=strfind(app.column,strcat('AI',num2str(L))); j=0; n=[];
                for i=1:length(b); if ~isempty(b{i}); j=j+1; n(j)=i; end; end
                for j=1:length(n)
                    switch AIstate(L)
            
                        case 3
                            disp('thermocouple')
                            new.InternalTemperature=calibrate_temperature_fx(120,app.(app.column{n(j)}),[],[]);
                            %output=calibrate_temperature_fx(gain,input_hj,input_cj,compensation)
                            new.InternalTemperature(isnan(new.InternalTemperature))=0;
                    end
                end
            end
            
            % update the columns
            
            
            K=find(~strcmp('InternalTemperature',app.column));
            app.column=app.column(K);
            app.column{end+1}='InternalTemperature';
            
            %update struct
            app.InternalTemperature=new.InternalTemperature;
            
            % h_=findobj('Tag','edit1LB'); set(h_,'String',app.column)
            % h_=findobj('Tag','edit2LB'); set(h_,'String',app.column)
            % h_=findobj('Tag','edit3LB'); set(h_,'String',app.column)
            %
            %
            % app.Done=1;
            % app.new=fieldnames(new)';
            guidata(hObject, handles);
            % plotta_ora(handles)
        end

        % Button pushed function: pushbutton19
        function pushbutton19_Callback(app, event)
            % hObject    handle to pushbutton19 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %% preamble
            %definisce la calibrazion
            
            if any(strcmp(fieldnames(handles),'smooth'));
                %msgbox('I will work only on not smoothed data')
                nomi=app.column;
                for i=1:length(nomi)
                    if strfind(nomi{i},'smooth'); handles=rmfield(handles,nomi{i});
                        K=find(~strcmp(nomi{i},app.column));
                        app.column=app.column(K);
                    end
                end
            end
            
            
            if any(strcmp(fieldnames(handles),'new'))
            
                nomi=app.new;
                for i=1:length(nomi)
                    handles = rmfield(handles, nomi(i));
                    K=find(~strcmp(nomi(i),app.column));
                    app.column=app.column(K);
                end
                if any(strcmp(fieldnames(handles),'new')); handles=rmfield(handles,'new');end
            end


            dint=findobj('Tag','Rint'); rint=str2double(get(dint,'String'))/2000;
            dext=findobj('Tag','Rext'); rext=str2double(get(dext,'String'))/2000;
            
            h_=findobj('Tag','calibration'); contents=get(h_,'Value');
            
            if contents==2; cal.tHG=73.86; cal.tLG=cal.tHG; fref=250; end
            if contents==3; cal.tHG=1117.17; cal.tLG=cal.tHG; fref=250; end
            if contents==4; cal.tHG=730.94; cal.tLG=cal.tHG; fref=250; end
            if contents==5; cal.tLG=1118; cal.tHG=cal.tLG*100; fref=250; end
            if contents==6; cal.tHG=1179.2; cal.tLG=0.736e6; fref=250; end
            if contents==7; cal.tHG=0; cal.tLG=0.736e6; fref=125;
                if isempty(app.Done); app.Stamp=app.Stamp*1.2; app.Time=app.Time*1.2; end
            end
            if contents==8; cal.tHG=0; cal.tLG=0.736e6; fref=125;
                if isempty(app.Done); app.Stamp=app.Stamp*1.24; app.Time=app.Time*1.25; end
            end
            if contents==9; cal.tHG=0; cal.tLG=0.736e6; fref=125;
                if isempty(app.Done); app.Stamp=app.Stamp*2; app.Time=app.Time*2; end
            end
            if contents==10; cal.tHG=0; cal.tLG=0.736e6; fref=125; end
            if contents==11; cal.tHG=0; cal.tLG=0.736e6; fref=125; end
            if contents==11; cal.tHG=0; cal.tLG=0.736e6; fref=125; end
            if contents==12; cal.tHG=0; cal.tLG=0.736e6; fref=100; cal.enc(1)=4/3*pi*(rext^2+rint*rext+rint^2)/(rext+rint)*10;
            end
            
            cal.tSG=17.19E6;
            
            cal.torqueHG(1:12)=cal.tHG*3/2/pi/(rext^3-rint^3)*1E-6;
            cal.torqueLG(1:12)=cal.tLG*3/2/pi/(rext^3-rint^3)*1E-6;
            cal.torqueSG(1:12)=cal.tSG*3/2/pi/(rext^3-rint^3)*1E-6;
            rint_o=rint;
            %rint=0;
            
            if contents>=11; cal.ax=-7.93457/pi/(rext^2-rint^2)/1000; %MPa
            else cal.ax=2.5/pi/(rext^2-rint^2)/1000; %MPa
            end
            
            %% calibrate only Normal, shear1, mu1
            
            %% calculate normal stress
            
            b=(strfind(app.column,'Axial')); j=0; n=[];
            for i=1:length(b); if ~isempty(b{i}); j=j+1; n(j)=i; end; end
            for j=1:length(n);
                eval(['new.Normal=app.' app.column{n(j)} '*cal.ax(j);'])
            end
            
            %% correct the normal stress with springs elasticity
            h_=findobj('Tag','GH');
            statoGH=get(h_,'Value');
            if statoGH==1
                % calibrazione Normal load gouge holder
                x=app.LVDT-app.LVDT(1);
                a=abs(x- 5.37);
                Ia=find(a==min(a),1,'first');
                new.dspring=(x - x(Ia))*cal.lv(1);
                new.dspring(1:Ia)=0;
            
                if contents>=11; new.NormalGH=(-7.93457*app.Axial-(0.2666+0.0501*new.dspring))/pi/(rext^2-rint^2)/1000; %MPa
                else new.NormalGH=(2.5*app.Axial-(0.2666+0.0501*new.dspring))/pi/(rext^2-rint^2)/1000;
                end
            
            end
            
            %% calibrate Torque, calculate shear and apparent mu
            
            b=(strfind(app.column,'Torque')); j=0; n=[];
            for i=1:length(b); if ~isempty(b{i}); j=j+1; n(j)=i; end; end
            
            for j=1:length(n)
                if or(~isempty(strfind(app.column{n(j)},'HG')),strcmp(app.column{n(j)},'Torque'))
                    cali=cal.torqueHG(j);
                elseif ~isempty(strfind(app.column{n(j)},'LG'))
                    cali=cal.torqueLG(j);
                end
            
                eval(['new.shear' num2str(j) '=app.' app.column{n(j)} '*cali;']);
                eval(['new.Mu' num2str(j) '=new.shear' num2str(j) './new.Normal;']);
            
                if statoGH==1;eval(['new.Mu' num2str(j) '=new.shear' num2str(j) './new.NormalGH;']); end
            end
            
            %% final routine to update the fields
            nomi=[];
            
            [a,b]=size(app.column); [aa,bb]=size(fieldnames(new));
            fname=fieldnames(new);
            for i=1:length(fname)
                K=find(~strcmp(fname{i},app.column));
                app.column=app.column(K);
            end
            inp1=app.column;
            
            if aa==1 & aa==b | bb==1 & a==1; inp1=app.column'; end
            nomi=[inp1 ; fieldnames(new)];
            
            app.column=[];
            app.column=nomi';
            
            nomi2=fieldnames(new);
            for i=1:length(nomi2)
                eval(['app.' char(nomi2(i)) '=new.' char(nomi2(i)) ';'])
            end
            
            h_=findobj('Tag','edit1LB'); set(h_,'String',app.column)
            h_=findobj('Tag','edit2LB'); set(h_,'String',app.column)
            h_=findobj('Tag','edit3LB'); set(h_,'String',app.column)
            
            
            app.Done=1;
            app.new=fieldnames(new)';
            guidata(hObject, handles);
            plotta_ora(handles)
        end

        % Button pushed function: pushbutton20
        function pushbutton20_Callback(app, event)
            % hObject    handle to pushbutton20 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % hObject    handle to calibration (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %definisce la calibrazion
            
            if any(strcmp(fieldnames(handles),'smooth'));
                %msgbox('I will work only on not smoothed data')
                nomi=app.column;
                for i=1:length(nomi)
                    if strfind(nomi{i},'smooth'); handles=rmfield(handles,nomi{i});
                        K=find(~strcmp(nomi{i},app.column));
                        app.column=app.column(K);
                    end
                end
            end
            
            
            if any(strcmp(fieldnames(handles),'new'))
            
                nomi=app.new;
                for i=1:length(nomi)
                    handles = rmfield(handles, nomi(i));
                    K=find(~strcmp(nomi(i),app.column));
                    app.column=app.column(K);
                end
                if any(strcmp(fieldnames(handles),'new')); handles=rmfield(handles,'new');end
            end


            dint=findobj('Tag','Rint'); rint=str2double(get(dint,'String'))/2000;
            dext=findobj('Tag','Rext'); rext=str2double(get(dext,'String'))/2000;
            
            h_=findobj('Tag','calibration'); contents=get(h_,'Value');
            
            cal.enc(1:2)=4/3*pi*(rext^2+rint*rext+rint^2)/(rext+rint);
            
            if contents==2; cal.tHG=73.86; cal.tLG=cal.tHG; fref=250; end
            if contents==3; cal.tHG=1117.17; cal.tLG=cal.tHG; fref=250; end
            if contents==4; cal.tHG=730.94; cal.tLG=cal.tHG; fref=250; end
            if contents==5; cal.tLG=1118; cal.tHG=cal.tLG*100; fref=250; end
            if contents==6; cal.tHG=1179.2; cal.tLG=0.736e6; fref=250; end
            if contents==7; cal.tHG=0; cal.tLG=0.736e6; fref=125;
                if isempty(app.Done); app.Stamp=app.Stamp*1.2; app.Time=app.Time*1.2; end
            end
            if contents==8; cal.tHG=0; cal.tLG=0.736e6; fref=125;
                if isempty(app.Done); app.Stamp=app.Stamp*1.24; app.Time=app.Time*1.25; end
            end
            if contents==9; cal.tHG=0; cal.tLG=0.736e6; fref=125;
                if isempty(app.Done); app.Stamp=app.Stamp*2; app.Time=app.Time*2; end
            end
            if contents==10; cal.tHG=0; cal.tLG=0.736e6; fref=125; end
            if contents==11; cal.tHG=0; cal.tLG=0.736e6; fref=125; end
            if contents==11; cal.tHG=0; cal.tLG=0.736e6; fref=125; end
            if contents==12; cal.tHG=0; cal.tLG=0.736e6; fref=100; cal.enc(1)=4/3*pi*(rext^2+rint*rext+rint^2)/(rext+rint)*10;
            end
            
            cal.tSG=17.19E6;
            cal.GEM=3.1;
            
            
            cal.torqueHG(1:12)=cal.tHG*3/2/pi/(rext^3-rint^3)*1E-6;
            cal.torqueLG(1:12)=cal.tLG*3/2/pi/(rext^3-rint^3)*1E-6;
            cal.torqueSG(1:12)=cal.tSG*3/2/pi/(rext^3-rint^3)*1E-6;
            rint_o=rint;
            %rint=0;
            
            if contents>=11; cal.ax=-7.93457/pi/(rext^2-rint^2)/1000; %MPa
            else cal.ax=2.5/pi/(rext^2-rint^2)/1000; %MPa
            end
            
            
            %rint=rint_o;
            
            cal.lv(1)=5.0634;
            cal.lv(2)=0.3; %0.12*cal.lv(1); %% cambiato!!!!
            cal.lv(3)=1;
            cal.fluids(1:12)=4; %MPa/mV
            
            %optional sensors in AI8-AI18
            
            AIstate=zeros(1,18);
            for L=1:18;
                obj=num2str(L); obj=strcat('popupAI',obj);
                if isempty(findobj('Tag',obj))
                else
                    h=findobj('Tag',obj);
                    AIstate(1,L)=get(h,'Value');
                end
            end
            
            cal.cosino=[8.737 0.857];
            %TC
            cal.ceriani=[1.667 9.333];
            cal.iscoP=[6.879 -0.328];
            cal.iscoV=[1 0];
            cal.iscoF=[1 0];
            cal.ftutaRH=[1/0.05 0];
            cal.ftutaT=[1/0.05 -20];
            cal.other=[1 0];
            
            %% ELABORAZIONE DATI
            
            %% calculate normal stress
            
            b=(strfind(app.column,'Axial')); j=0; n=[];
            for i=1:length(b); if ~isempty(b{i}); j=j+1; n(j)=i; end; end
            for j=1:length(n);
                eval(['new.Normal=app.' app.column{n(j)} '*cal.ax(j);'])
            end
            
            %% correct the normal stress with springs elasticity
            h_=findobj('Tag','GH');
            statoGH=get(h_,'Value');
            if statoGH==1
                % calibrazione Normal load gouge holder
                x=app.LVDT-app.LVDT(1);
                a=abs(x- 5.37);
                Ia=find(a==min(a),1,'first');
                new.dspring=(x - x(Ia))*cal.lv(1);
                new.dspring(1:Ia)=0;
            
                if contents>=11; new.NormalGH=(-7.93457*app.Axial-(0.2666+0.0501*new.dspring))/pi/(rext^2-rint^2)/1000; %MPa
                else new.NormalGH=(2.5*app.Axial-(0.2666+0.0501*new.dspring))/pi/(rext^2-rint^2)/1000;
                end
            
            end
            
            %% calibrate optional sensors
            
            for L=1:18;
                b=strfind(app.column,strcat('AI',num2str(L))); j=0; n=[];
                for i=1:length(b); if ~isempty(b{i}); j=j+1; n(j)=i; end; end
                for j=1:length(n);
                    switch AIstate(L)
                        case 0
                            disp('no channel')
                        case 1
                            disp('no sensor')
                        case 2
                            disp('cosino')
                            eval(['new.InternalPressure=app.' app.column{n(j)} '*cal.cosino(1)+cal.cosino(2);'])
                        case 3
                            disp('thermocouple')
                            eval(['new.InternalTemperature=calibrate_temperature_fx(120,app.' app.column{n(j)} ',[],[]);'])
                            %output=calibrate_temperature_fx(gain,input_hj,input_cj,compensation)
                        case 4
                            disp('ceriani')
                            eval(['new.ChamberPressure=10.^(app.' app.column{n(j)} '*cal.ceriani(1)+cal.ceriani(2));'])
                        case 5
                            disp('iscoP')
                            eval(['new.PumpPressure=app.' app.column{n(j)} '*cal.iscoP(1)+cal.iscoP(2);'])
                        case 6
                            disp('iscoV')
                            eval(['new.PumpVolume=app.' app.column{n(j)} '*cal.iscoV(1)+cal.iscoV(2);'])
                        case 7
                            disp('iscoF')
                            eval(['new.PumpFlow=app.' app.column{n(j)} '*cal.iscoF(1)+cal.iscoF(2);'])
                        case 8
                            disp('FtutaRH')
                            eval(['new.ProbeH=app.' app.column{n(j)} '*cal.FtutaRH(1)+cal.FtutaRH(2);'])
                        case 9
                            disp('FtutaT')
                            eval(['new.ProbeT=app.' app.column{n(j)} '*cal.FtutaT(1)+cal.FtutaT(2);'])
                        case 10
                            disp('even more sensors??')
                            eval(['new.Other=app.' app.column{n(j)} '*cal.other(1)+cal.other(2);'])
                    end
                end
            end
            
            %% pore fluids (old)
            
            % h_=findobj('Tag','fluid');
            % statoF=get(h_,'Value');
            %
            % if statoF==1
            % b=(strfind(app.column,'IO')); j=0; n=[];
            % for i=1:length(b); if ~isempty(b{i}); j=j+1; n(j)=i; end; end
            % for j=1:length(n);
            %         eval(['new.Pf=app.' app.column{n(j)} '*cal.fluids(1);']);
            %         eval(['new.EffPressure=new.Normal-new.Pf;']);
            % end
            
            % if contents>=12; b=(strfind(app.column,'GefranPressure')); j=0; n=[];
            % else b=(strfind(app.column,'FluidPressure')); j=0; n=[];
            % end
            %
            % for i=1:length(b); if ~isempty(b{i}); j=j+1; n(j)=i; end; end
            % for j=1:length(n);
            %         eval(['new.Pf=app.' app.column{n(j)} '*cal.fluids(1);']);
            %         eval(['new.EffPressure=new.Normal-new.Pf;']);
            % end
            % end
            
            %% calibrate gems pressure transducer
            if any(strcmp(fieldnames(handles),'Gems'))
                new.GEM=app.Gems*cal.GEM;
            elseif any(strcmp(fieldnames(handles),'GEMSPressure'))
                new.GEM=app.GEMSPressure*cal.GEM;
            end
            
            %% pore fluid pressure
            
            h_=findobj('Tag','popupPF');
            popupPF=get(h_,'Value');
            
            switch popupPF
                case    1
                    disp('No pore pressure')
                case    2
                    disp('Gefran is measuring pore pressure')
                    if contents>=12; b=(strfind(app.column,'GefranPressure')); j=0; n=[];
                    elseif contents<=11; b=(strfind(app.column,'FluidPressure')); j=0; n=[];
                    else b=strfind(app.column,'IO'); j=0; n=[];
                    end
            
                    for i=1:length(b); if ~isempty(b{i}); j=j+1; n(j)=i; end; end
                    for j=1:length(n);
                        eval(['new.Pf=app.' app.column{n(j)} '*cal.fluids(1);']);
                        eval(['new.EffPressure=new.Normal-new.Pf;']);
                    end
                case    3
                    disp('Gems is measuring pore pressure')
                    b=strfind(app.column,'GEMS'); j=0; n=[];
                    for i=1:length(b); if ~isempty(b{i}); j=j+1; n(j)=i; end; end
                    for j=1:length(n);
                        eval(['new.Pf=app.' app.column{n(j)} '*3.15911 - 2.557;']);
                        eval(['new.EffPressure=new.Normal-new.Pf;']);
                    end
                case    4
                    disp('IscoPump is measuring pore pressure')
                    gigione=input('What is the exact name of Isco Pump vector? ','s');
            
                    b=strfind(app.column,gigione); j=0; n=[];
                    for i=1:length(b); if ~isempty(b{i}); j=j+1; n(j)=i; end; end
                    for j=1:length(n);
                        eval(['new.Pf=app.' app.column{n(j)} '*cal.iscoP(1) + cal.iscoP(2);']);
                        eval(['new.EffPressure=new.Normal-new.Pf;']);
                    end
            end
            
            %% confining pressure
            
            h_=findobj('Tag','popupPC');
            popupPC=get(h_,'Value');
            
            switch popupPC
                case    1
                    disp('No confining pressure')
                case    2
                    disp('Gefran is measuring confining pressure')
                    if contents>=12; b=(strfind(app.column,'GefranPressure')); j=0; n=[];
                    elseif contents<=11; b=(strfind(app.column,'FluidPressure')); j=0; n=[];
                    else b=strfind(app.column,'IO'); j=0; n=[];
                    end
            
                    for i=1:length(b); if ~isempty(b{i}); j=j+1; n(j)=i; end; end
                    for j=1:length(n);
                        eval(['new.Pc=app.' app.column{n(j)} '*cal.fluids(1);']);
                    end
                case    3
                    disp('Gems is measuring confining pressure')
                    b=strfind(app.column,'GEMS'); j=0; n=[];
                    for i=1:length(b); if ~isempty(b{i}); j=j+1; n(j)=i; end; end
                    for j=1:length(n);
                        eval(['new.Pc=app.' app.column{n(j)} '*3.15911 - 2.557;']);
                    end
                case    4
                    disp('IscoPump is measuring confining pressure')
                    gigione=input('What is the exact name of Isco Pump vector? ','s');
            
                    b=strfind(app.column,gigione); j=0; n=[];
                    for i=1:length(b); if ~isempty(b{i}); j=j+1; n(j)=i; end; end
                    for j=1:length(n);
                        eval(['new.Pc=app.' app.column{n(j)} '*cal.iscoP(1) + cal.iscoP(2);']);
                    end
            end
            
            %% calculation of velocity and slip
            % encoders
            
            h=findobj('Tag','nodeEnc1');
            node=str2num(get(h,'String'));
            node1=node(:,1);
            f1crat=node(:,2);
            
            h=findobj('Tag','nodeEnc2');
            node=str2num(get(h,'String'));
            node2=node(:,1);
            f2crat=node(:,2);
            
            h=findobj('Tag','MaxEnc');
            maxE=str2double(get(h,'String')) ;
            
            b=(strfind(app.column,'Encoder')); j=0; n=[];
            for i=1:length(b); if ~isempty(b{i}); j=j+1; n(j)=i; end; end
            for j=1:length(n);
                clear d0 d1 d2 v vel
                eval(['d0=app.' app.column{n(j)} ';']) %d0 is the initial, raw encoder data
                Dd0 = diff(d0);
                Dd0(Dd0 == 0) = [];
                min(Dd0)
                if abs(min(Dd0)) < 1/4000
                    disp('Fine encoder')
                else
                    disp('Coarse encoder')
                end
                h_ele=app.Stamp/1000;
                % qui provo a ridurre il problema del cambiamento in corsa di acq xlab.
                % per rirpistinare la versione di shivaUNIX precedente imposta I_ele=[] e commenta la riga di seguito.
            
                hOb=findobj('Tag','AdjRate'); f_ele=get(hOb,'Value');
                I_ele=find(diff(h_ele)<mode(diff(h_ele)));
                testE=2;
            
                %=== se necessario aggiustare il rate
                if ~isempty(I_ele) && f_ele==1
                    testE=1; Fs=[];
                    sl_ele=d0;
                    I_range=[I_ele: I_ele+1];
                    I_fit=[I_ele-10:I_ele];
                    I_inter=[I_ele:I_ele+10];
                    cv=fit(app.Time(I_fit)/1000, sl_ele(I_fit),'linear');
                    deltaslip=sl_ele(I_ele+1) - cv(app.Time(I_ele+1)/1000);
                    sl_ele(I_ele+1:end)=sl_ele(I_ele+1:end)/1000 - deltaslip(1);
                    d0=sl_ele;
                end
            
            
                %che i massimi siano fuori dal rumore
                J=local_max(d0); I=[];
                if length(J) > 10
                    [c,d]=hist(diff(d0(J))); Jc=find(c==max(c));
                    r=abs(diff(d0(J))-d(Jc(1))); In=find(r > mode(diff(d))); I=J(In);
                end
            
                I=J; %cambiato 15/03
                %figure; plot(d0); hold on; plot(I,d0(I),'*r')
            
                fO=findobj('Tag','incremental'); fOV=get(fO,'value')
                if fOV==1
                    if ~isempty(I)
                        for ii=1:length(I)
                            if ii==length(I) & I(ii) + 2 < length(d0)
                                d0(I(ii)+1:end)=d0(I(ii)+1:end)-d0(I(ii)+2)+d0(I(ii));
                            elseif ii==length(I) & I(ii) +2 >= length(d0)
                                break
                            else
                                d0(I(ii)+1:I(ii+1))=d0(I(ii)+1:I(ii+1))-d0(I(ii)+2)+d0(I(ii));
                            end
                        end
                    end
                end %if fOV
            
                d0(d0<0)=0;
            
                ITorq=findobj('Tag','Torque');
                ITorqO=get(ITorq,'Value');
            
                h=app.Stamp/1000;
                d1=d0*cal.enc(1);
                % figure(22)
                plot(d1)
                if ITorqO==0
                    fcamp=(1./max(1/fref,h));
                    Fs=max(fcamp);
                    alphas=ceil(log10(Fs));
                    fc=max(fref/100,Fs/10^(alphas));
                    disp(['fc= ',num2str(fc)])
                    nodes=node2; fcrat=f2crat;
                    if i==1; nodes=node1; fcrat=f1crat;
                    end
                    if Fs > fref/100
                        disp('red')
                        %riduci l'effetto window
                        d=fdesign.lowpass('N,Fc',nodes,fcrat,Fs);
            
                        Hd=design(d,'window','Window',tukeywin(nodes+1,0));
                        d_sm=filtfilt(Hd.Numerator,1,d1);
                    else
                        d_sm=d1;
                    end
                else
                    d_sm=d1;
                end


                % na=1;
                % dtn=mode(h)./na;
                %
                % if na > 1
                %     % serve a ricampionare e calcolare la V con maggiore dettaglio.
                %     % Cambiare na all'occorrenza
                % xx=app.Time(1)/1000:dtn:app.Time(end)/1000;
                % cs=spline(app.Time/1000, d_sm);
                % d_sm1=ppval(cs,xx);
                % bb=zeros(size(d_sm1));
                % bb(2:end)=diff(d_sm1); bb(1)=bb(2);
                % h_1=diff(xx); h1=h_1; h1(2:end+1)=h_1;
                % v1=bb./h1;
                % v=resample(v1,1,na)';
                % d_sm=resample(d_sm1, 1, na)';
                % a=length(v) - length(h);
                % for ia=1:a
                % v(end+1)=v(end);
                % d_sm(end+1)=d_sm(end);
                % end
                % else
                bb=zeros(size(d_sm));
                bb=diff(d_sm); bb(end+1)=bb(end);
                v=(bb./h)./app.tconv;
                % end
            
                eval(['new.SlipVel_Enc_' num2str(j) '=v;']);
                eval(['new.Slip_Enc_' num2str(j) '=(d_sm);']);
            
            
                %if j==2; v=smooth(v,300); end
                if j==1; del0=d_sm; v0=v; end
                if j==2;
                    del10=max(d_sm,del0);
                    Iv=find(v < 0.01 ); %% cambiare
                    d_sm(Iv)=del0(Iv);
                    v=max(v0,v);
                    del=max(del0,d_sm);
                    %if j==2; adel=abs(d_sm-del0)./del0.*100; Iadel=find(adel < 1);
                    %del(Iadel)=del0(Iadel);
                    %end
                    %bb=zeros(size(del));
                    %bb(2:end)=diff(del); bb(1)=bb(2);
            
                    %if j==1; Ivmax=find(v<=0.05); new.vel=zeros(size(v)); new.vel(Ivmax)=v(Ivmax); end
                    %if j==2; Ivmax=find(v>0.05);  new.vel(Ivmax)=new.SlipVel_Enc_2(Ivmax); end
                    new.slip=del10;
                    if max(v) <= 20E-3
                        new.vel=v0;
                    else
                        new.vel=v; %bb./h;
                    end
            
                    %find outlayers in vel
                    IOL=find(new.vel >=10);
                    for i=1:length(IOL)
                        new.vel(IOL(i))=new.vel(IOL(i)-1);
                    end
                    IOL=find(new.vel < -1);
                    for i=1:length(IOL)
                        new.vel(IOL(i))=new.vel(IOL(i)-1);
                    end
            
                end
            end
            
            %% calibrate LVDT
            
            b=(strfind(app.column,'LVDT')); j=0; n=[];
            for i=1:length(b); if ~isempty(b{i}); j=j+1; n(j)=i; end; end
            for j=1:length(n); ...
                    if any(strfind(app.column{n(j)},'LVDT_')); continue;
                    else
                        if j==1; eval(['new.LVDT_low= app.' app.column{n(j)} '*cal.lv(j);']); end
                        if j==2; eval(['new.LVDT_high= app.' app.column{n(j)} '*cal.lv(j);']); end
            
                    end
            end
            
            %% calibrate Torque, calculate shear and mu/effective mu
            b=(strfind(app.column,'Torque')); j=0; n=[];
            for i=1:length(b); if ~isempty(b{i}); j=j+1; n(j)=i; end; end
            
            for j=1:length(n)
                if or(~isempty(strfind(app.column{n(j)},'HG')),strcmp(app.column{n(j)},'Torque'))
                    cali=cal.torqueHG(j);
                elseif ~isempty(strfind(app.column{n(j)},'LG'))
                    cali=cal.torqueLG(j);
                end
            
                %   if ITorqO==1 & testE==2 & Fs > fref/100
                %    eval(['new.shear' num2str(j) '=filtfilt(Hd.Numerator,1,app.' app.column{n(j)} '*cali);']);
                %   else
                eval(['new.shear' num2str(j) '=app.' app.column{n(j)} '*cali;']);
                %   end
                eval(['new.Mu' num2str(j) '=new.shear' num2str(j) './new.Normal;']);
            
                h_=findobj('Tag','fluid');
                statoF=get(h_,'Value');
            
                % non sono sicura    if statoGH==1;eval(['new.Mu' num2str(j) '=new.shear' num2str(j) './new.NormalGH;']); end
                if (statoF==1) OR (popupPF>=2); eval(['new.Mu' num2str(j) '=new.shear' num2str(j) './new.EffPressure;']); end
            end
            
            %% import the thermocouple recording
            
            h_=findobj('Tag','TC');
            statoTC=get(h_,'Value');
            
            if statoTC==1 && isempty(dir('*TC'))==0;
                %thermocouple
                filname=dir('*TC');
            
                if isempty(filname) | length(filname) > 1
                    %    nn=app.filename(1:4)
                    %    xx=dir(['~/shivadir/Shiva Experiments/' nn '*']);
                    %    if ~isempty(xx)
                    %    yy=dir(['~/shivadir/Shiva Experiments/' xx.name '/*TC*']);
                    %    if ~isempty(yy) & length(yy)==1
                    [filename,PathName] = uigetfile('*.*','All Files (*.*)', ...
                        pwd);
            
                    clear filname
                    filname.name=filename;
            
                    %    filname=struct('name',{['~/shivadir/Shiva Experiments/' xx.name '/' yy.name]});
                    %    end
                    %    end
                end
            
                TC=importdata(filname.name,'\t',2);
                time2=cumsum(app.Stamp);
            
                [a,b]=size(TC.data);
            
                if b==5;
                    time=(1:length(TC.data)).*400;
                    new.T1=interp1(time,TC.data(:,1),time2);
                    new.T2=interp1(time,TC.data(:,2),time2);
                    new.T3=interp1(time,TC.data(:,3),time2);
                    new.T4=interp1(time,TC.data(:,4),time2);
                elseif b==2
            
                    %time=(1:length(TC.data)).*mode(TC.data(:,2));
                    time=cumsum(TC.data(:,2));
                    %time=time./time(end).*time2(end);
                    new.T1=interp1(time,TC.data(:,1),time2);
                else
                    T1=Tmeas(TC.data(:,1).*1000);
                    h_=findobj('Tag','T0'); T1=str2num(get(h_,'String'));
                    T2=Tmeas_3(TC.data(:,1),T1);
                    T=T2;
                    time=(1:length(TC.data)).*80;
                    new.T1=interp1(time,T,time2);
                end
            end
            
            %% Estimate temperature
            dn=50;
            time2=cumsum(app.Stamp);
            
            if any(strcmp(fieldnames(new),'vel'))
                [Temp]=temp(app.Time/1000,new.vel,new.shear1,new.slip, dn); %change for vel
            
            elseif max(new.vel) <= 20e-3
                [Temp]=temp(app.Time/1000,new.SlipVel_Enc_1,new.shear1,new.Slip_Enc_1,dn);
            else
                [Temp]=temp(app.Time/1000,new.SlipVel_Enc_2,new.shear1,new.Slip_Enc_2,dn); %change for vel
            end
            new.TempE=interp1(time2(1:dn:end),Temp,time2);
            
            b=(strfind(app.column,'StrainGauge')); j=0; n=[];
            for i=1:length(b); if ~isempty(b{i}); j=j+1; n(j)=i; end; end
            for j=1:length(n); ...
                    eval(['new.StrainGauge' num2str(j) '=app.' app.column{n(j)} '*cal.torqueSG(j);']);
                eval(['new.MuSG' num2str(j) '=new.StrainGauge' num2str(j) './new.Normal;']);
            end
            
            %% import the Isco pump recording
            
            if isempty(dir('*IP'))==0 || isempty(dir('*ISCO'))==0;
                %thermocouple
                filname=[dir('*IP') dir('*ISCO')];
            
                if isempty(filname) | length(filname) > 1
                    %    nn=app.filename(1:4)
                    %    xx=dir(['~/shivadir/Shiva Experiments/' nn '*']);
                    %    if ~isempty(xx)
                    %    yy=dir(['~/shivadir/Shiva Experiments/' xx.name '/*TC*']);
                    %    if ~isempty(yy) & length(yy)==1
                    [filename,PathName] = uigetfile('*.*','All Files (*.*)', ...
                        pwd);
            
                    clear filname
                    filname.name=filename;
            
                    %    filname=struct('name',{['~/shivadir/Shiva Experiments/' xx.name '/' yy.name]});
                    %    end
                    %    end
                end
            
                IP=importdata(filname.name,'\t',1);
            
                for i=1:size(IP.data,2)
                    header=strrep(IP.colheaders{1,i},'_','');
                    header=strrep(header,'(s)','');
                    header=strrep(header,'(ms)','');
                    header=strrep(header,'(kPa)','');
                    header=strrep(header,'(MPa)','');
                    header=strrep(header,'(mL/min)','');
                    header=strrep(header,'(mL)','');
            
                    new.(header)=IP.data(:,i);
            
                    %         eval(['new.'  IP.colheaders{1,i} '=IP.data(1,' i ');']);
                end
            end
            
            %% calibrate the room humidity sensor
            %HR and TA
            
            
            b=(strfind(app.column,'RH')); j=0; n=[];
            for i=1:length(b); if ~isempty(b{i}); j=j+1; n(j)=i; end; end
            for j=1:length(n);
                eval(['new.HRc=app.' app.column{n(j)} './0.05;'])
            end
            
            b=(strfind(app.column,'TA')); j=0; n=[];
            for i=1:length(b); if ~isempty(b{i}); j=j+1; n(j)=i; end; end
            for j=1:length(n);
                eval(['new.TAc=(app.' app.column{n(j)} './0.05)-20;'])
            end
            %% calibrate the vacuum gauge
            
            h_=findobj('Tag','vac');
            statoVAC=get(h_,'Value');
            
            if statoVAC==1;
            
                new.VAC=10.^(app.TA*1.667-9.333);
            else %keyboard
            end
            % clear statoVAC
            % Vac=10.^(TA*1.667-9.333); %Pa
            
            %% calculate gouge layer thickness
            
            h_=findobj('Tag','get_thickness');
            statoTHICKNESS=get(h_,'Value');
            
            h_=findobj('Tag','zero_thickness_long'); % volts
            ztl=get(h_,'String');
            ztl=str2double(ztl);
            
            h_=findobj('Tag','zero_thickness_short'); % mm
            zts=get(h_,'String');
            zts=str2double(zts);
            
            if statoTHICKNESS==1
            
                b=(strfind(app.column,'LVDT')); j=0; n=[];
                for i=1:length(b); if ~isempty(b{i}); j=j+1; n(j)=i; end; end
                for j=1
                    new.thickness_low=(app.(app.column{n(j)})-ztl)*cal.lv(1);
                end
            
                b=(strfind(app.column,'short')); j=0; n=[];
                for i=1:length(b); if ~isempty(b{i}); j=j+1; n(j)=i; end; end
                for j=1
                    new.thickness_high=zts-cal.lv(2)*(app.(app.column{n(j)}));
                end
            
            else %keyboard
            end
            
            %% import the GEF data (recording of the gefranexpress)
            
            ax_=findobj('Tag','Gefran'); statoGEF=get(ax_,'Value');
            
            if statoGEF==1;
            
                I=find(abs(app.timeGEF)==min(abs(app.timeGEF))); if isempty(I); I=1; end
                J=find(abs(app.Time)==min(abs(app.Time))); if isempty(J); J=1; end
            
                if length(new.Normal)>=length(app.VGEF)
                    I2=length(app.VGEF);
                    if I2+J > length(new.Normal); I2=I2-J; end
                    J2=J-1+I2;
            
                    new.SpeedGEF=zeros(size(new.Normal)); new.SpeedGEF(J:J2)=(-1)*app.VGEF(I:I2).*cal.enc(1)/60;
                    new.SlipGEF=zeros(size(new.Normal)); new.SlipGEF(J:J2)=cumsum((-1)*app.VGEF(I:I2))*cal.enc(1)/60/1000;
                    if any(find(strcmp(fieldnames(handles),'TqGEF')));
                        new.TorqueGEF=zeros(size(new.Normal)); new.TorqueGEF(J:J2)=(-1)*app.TqGEF(I:I2);
                    end
            
                else
            
                    l=J+length(app.VGEF)-1; resl=l-length(app.Time);
                    new.SpeedGEF(J:length(app.Time))=(-1)*app.VGEF(1:length(app.VGEF)-resl).*cal.enc(1)/60;
                    new.SlipGEF(J:length(app.Time))=cumsum((-1)*app.VGEF(1:length(app.VGEF)-resl))*cal.enc(1)/60/1000;
                    if any(find(strcmp(fieldnames(handles),'TqGEF')));
                        new.TorqueGEF(J:J+length(app.VGEF)-1)=(-1)*app.TqGEF;
                    end
            
                end
            
                ax_=findobj('Tag','Gefran'); set(ax_,'Value',0)
                handles=rmfield(handles,'VGEF');
                handles=rmfield(handles,'timeGEF');
            
                if strcmp(app.column,'TqGEF'); handles=rmfield(handles,'TqGEF'); end
                I=find(~strcmp(app.column,'TqGEF')); if ~isempty(I); app.column=app.column(I); end
                I=find(~strcmp(app.column,'VGEF')); if ~isempty(I); app.column=app.column(I); end
                I=find(~strcmp(app.column,'timeGEF')); if  ~isempty(I);  app.column=app.column(I); end
            
            end
            
            %% final routine to update the fields
            nomi=[];
            
            [a,b]=size(app.column); [aa,bb]=size(fieldnames(new));
            fname=fieldnames(new);
            for i=1:length(fname)
                K=find(~strcmp(fname{i},app.column));
                app.column=app.column(K);
            end
            inp1=app.column;
            
            if aa==1 & aa==b | bb==1 & a==1; inp1=app.column'; end
            nomi=[inp1 ; fieldnames(new)];
            
            app.column=[];
            app.column=nomi';
            
            nomi2=fieldnames(new);
            for i=1:length(nomi2)
                eval(['app.' char(nomi2(i)) '=new.' char(nomi2(i)) ';'])
            end
            
            h_=findobj('Tag','edit1LB'); set(h_,'String',app.column)
            h_=findobj('Tag','edit2LB'); set(h_,'String',app.column)
            h_=findobj('Tag','edit3LB'); set(h_,'String',app.column)
            
            
            app.Done=1;
            app.new=fieldnames(new)';
            guidata(hObject, handles);
            plotta_ora(handles)
        end

        % Button pushed function: refresh_thickness
        function refresh_thickness_Callback(app, event)
            % hObject    handle to refresh_thickness (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %% calculate gouge layer thickness
            cal.lv(1)=5.0634;
            cal.lv(2)=0.3;
            
            h_=findobj('Tag','zero_thickness_long'); % volts
            ztl=get(h_,'String');
            ztl=str2double(ztl);
            
            h_=findobj('Tag','zero_thickness_short'); % mm
            zts=get(h_,'String');
            zts=str2double(zts);
            
            b=(strfind(app.column,'LVDT')); j=0; n=[];
            for i=1:length(b); if ~isempty(b{i}); j=j+1; n(j)=i; end; end
            for j=1
                new.thickness_low=(app.(app.column{n(j)})-ztl)*cal.lv(1);
            end
            
            b=(strfind(app.column,'short')); j=0; n=[];
            for i=1:length(b); if ~isempty(b{i}); j=j+1; n(j)=i; end; end
            for j=1
                new.thickness_high=zts-cal.lv(2)*(app.(app.column{n(j)}));
            end
            
            
            %% final routine to update the fields
            nomi=[];
            
            [a,b]=size(app.column); [aa,bb]=size(fieldnames(new));
            fname=fieldnames(new);
            for i=1:length(fname)
                K=find(~strcmp(fname{i},app.column));
                app.column=app.column(K);
            end
            inp1=app.column;
            
            if aa==1 & aa==b | bb==1 & a==1; inp1=app.column'; end
            nomi=[inp1 ; fieldnames(new)];
            
            app.column=[];
            app.column=nomi';
            
            nomi2=fieldnames(new);
            for i=1:length(nomi2)
                eval(['app.' char(nomi2(i)) '=new.' char(nomi2(i)) ';'])
            end
            
            h_=findobj('Tag','edit1LB'); set(h_,'String',app.column)
            h_=findobj('Tag','edit2LB'); set(h_,'String',app.column)
            h_=findobj('Tag','edit3LB'); set(h_,'String',app.column)
            
            
            app.Done=1;
            app.new=fieldnames(new)';
            guidata(hObject, handles);
            plotta_ora(handles)
        end

        % Button pushed function: running
        function running_Callback(app, event)
            % hObject    handle to running (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            hOb=findobj('Tag','XLab');
            h_ele=get(hOb,'Value');
            
            if h_ele==2
                t_cut=app.Time/1000;
            elseif h_ele==3;
                if any(strcmp(fieldnames(handles),'slip'))
                    t_cut=app.slip;
                elseif any(strcmp(fieldnames(handles),'Slip_Enc_2'))
                    t_cut=app.Slip_Enc_2;
                end
            elseif h_ele==1
                t_cut=app.Rate;
            end
            
            [xi,yi]=ginput(2) ;
            mat(1,:)=abs(t_cut-ones(size(t_cut))*xi(1));
            mat(2,:)=abs(t_cut-ones(size(t_cut))*xi(2));
            ll(:,1)=find(mat(1,:)==min(mat(1,:)));
            ll(:,2)=find(mat(2,:)==min(mat(2,:)));
            
            
            ax_=get(gcf,'CurrentAxes');
            for n=1:3
                eval(['s=find(ax_==app.axes' num2str(n) ');'])
                if s==1; break; end
            end
            finestra=n;
            posy=get(ax_,'Ylim');
            posx=get(ax_,'Xlim');
            
            eval(['h_=findobj(''Tag'',''edit' num2str(n) ''');']); %n=numero asse
            s=str2double(get(h_,'String'));                        %numero della colonna
            
            %cla
            
            eval(['mmed_torq=mean(smooth(app.' app.column{s} '(ll(:,1):ll(:,2),:)));']);
            eval(['app.' app.column{s} '=app. ' app.column{s} '- mmed_torq;']);
            eval(['plot(app.XLab,app.' app.column{s} ',''ob'',''parent'',app.axes' num2str(finestra) ''');']);
            set(ax_,'Xlim',posx)
            
            guidata(hObject, handles);
            plotta_ora(handles)
        end

        % Menu selected function: saveRED
        function saveRED_Callback(app, event)
            % hObject    handle to saveRED (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % hObject    handle to save (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            pat0=pwd;
            [nome,pat]=uiputfile( ...
                {'*.m;*.fig;*.mat;*.mdl', 'All MATLAB Files (*.m, *.fig, *.mat, *.mdl)'; ...
                '*.*',                   'All Files (*.*)'}, ...
                'Save as',[pat0 '/' app.filename]);
            cd (pat)
            
            
            h_=findobj('Tag','fluid');
            statoF=get(h_,'Value');
            h_=findobj('Tag','GH');
            statoGH=get(h_,'Value');
            
            if statoF==1
                app.save={'Time' 'shear1' 'EffPressure' 'Mu1' 'Pf' 'LVDT_low' 'LVDT_high' 'vel' 'slip' 'TempE' 'TempM'};
            elseif statoGH==1
                app.save={'Time' 'shear1' 'Normal' 'Mu1' 'dspring' 'LVDT_low' 'vel' 'slip'}; %'TempE' 'TempM'};
            else
                app.save={'Time' 'shear1' 'Normal' 'Mu1' 'LVDT_low' 'vel' 'slip' 'TempE'};
            end
            
            
            for j=1:length(app.save)
                if j==length(app.save)
                    M(j,1)={['''' app.save{j} '''']};
                else
                    M(j,1)={['''' app.save{j} '''' ',']};
                end
            end
            M1=cell2mat(M');
            
            %for j=1:length(app.column)
            %    if j==length(app.column)
            %        O(j,1)={['''v' num2str(j) '''']};
            %    else
            %        O(j,1)={['''v' num2str(j) '''' ',']};
            %    end
            
            %    O1=cell2mat(O');
            %end
            
            %file header
            name4=['header', nome];
            %fid1 = fopen(name4,'wt');
            
            %fprintf(fid1,' load=%d\n shearT=%d\n trigger=%d\n cuttingfrom=%d to=%d\n dt=%f\n smooth=%d\n', ...
            %    app.loadT, app.shearT,app.triggered,app.cutted(1,1),app.cutted(1,2),app.dt,app.sm);
            %fprintf(fid1,' load=%d\n shearT=%d\n trigger=%d\n cuttingfrom=%d to=%d\n dt=%f\n smooth=%d\n', ...
            %    app.loadT, app.shearT,app.triggered,app.dt,app.sm);
            %fclose(fid1);
            
            nome2=[nome, '.mat'];
            %nome3=['originali', nome];
            
            
            eval(['save(nome2,''-struct'',''handles'',' M1 ');'])
            %eval(['save(nome3,''-struct'',''handles'',' O1 ');'])
            %save('parametri','-struct','handles','loadT','shearT','triggered','cutted'
            %,'dt','sm')
        end

        % Menu selected function: save
        function save_Callback(app, event)
            % hObject    handle to save (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            pat0=pwd;
            [nome,pat]=uiputfile( ...
                {'*.m;*.fig;*.mat;*.mdl', 'All MATLAB Files (*.m, *.fig, *.mat, *.mdl)'; ...
                '*.*',                   'All Files (*.*)'}, ...
                'Save as',[pat0 '/' app.filename]);
            cd (pat)
            
            app.save=app.column;
            
            
            for j=1:length(app.save)
                if j==length(app.save)
                    M(j,1)={['''' app.save{j} '''']};
                else
                    M(j,1)={['''' app.save{j} '''' ',']};
                end
            end
            M1=cell2mat(M');
            
            %for j=1:length(app.column)
            %    if j==length(app.column)
            %        O(j,1)={['''v' num2str(j) '''']};
            %    else
            %        O(j,1)={['''v' num2str(j) '''' ',']};
            %    end
            
            %    O1=cell2mat(O');
            %end
            
            %file header
            name4=['header', nome];
            %fid1 = fopen(name4,'wt');
            
            %fprintf(fid1,' load=%d\n shearT=%d\n trigger=%d\n cuttingfrom=%d to=%d\n dt=%f\n smooth=%d\n', ...
            %    app.loadT, app.shearT,app.triggered,app.cutted(1,1),app.cutted(1,2),app.dt,app.sm);
            %fprintf(fid1,' load=%d\n shearT=%d\n trigger=%d\n cuttingfrom=%d to=%d\n dt=%f\n smooth=%d\n', ...
            %    app.loadT, app.shearT,app.triggered,app.dt,app.sm);
            %fclose(fid1);
            
            nome2=[nome, 'RED.mat'];
            %nome3=['originali', nome];
            
            
            eval(['save(nome2,''-struct'',''handles'',' M1 ');'])
        end

        % Value changed function: smooth
        function smooth_Callback(app, event)
            % hObject    handle to smooth (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of smooth as text
            %        str2double(get(hObject,'String')) returns contents of smooth as a double
            
            set(hObject,'Enable','on','BackGroundColor','white')
            app.sm=str2num(get(hObject,'String'));
            
            if isempty(app.sm); app.sm=500; end
            ginput(1)
            ax_=get(gcf,'CurrentAxes');
            
            for n=1:3
                eval(['s=find(ax_==app.axes' num2str(n) ');'])
                if s==1; break; end
            end
            finestra=n;
            
            posy=get(ax_,'Ylim');
            posx=get(ax_,'Xlim');
            
            eval(['h_=findobj(''Tag'',''edit' num2str(n) ''');']); %n=numero asse
            s=str2double(get(h_,'String'));  %numero della colonna
            
            %if any(strcmp(app.column,{[app.column{s} 'o']}));
            %s=find(strcmp(app.column,{[app.column{s} 'o']}));
            %end
            
            %running mean
            eval(['pippo=(app.' app.column{s} ');']);
            if (app.sm)/2==floor(app.sm/2); app.sm=app.sm+1; end %check sul numero dispari
            l=(app.sm-1)/2; %es:(101-1)/2=50;
            
            pm=pippo(l+1:length(pippo)-l);
            for i=l+1:length(pippo)-l;
                pm(i-l)=sum(pippo(i-l:i+l));
            end
            pippo(l+1:length(pippo)-l)=pm/(app.sm);
            
            %windowSize = app.sm;
            %pm2=filter(ones(1,windowSize)/windowSize,1,pippo);
            %smooths data Y using a app.sm -point moving average.
            %eval(['app.' app.column{s} '=smooth(app.' app.column{s} ',app.sm);'])
            
            eval(['app.' app.column{s} 'o=app.' app.column{s} ';'])
            eval(['app.' app.column{s} '=pippo;'])
            if ~strcmp(app.column,{[app.column{s} 'o']});
                app.column(end+1)={[app.column{s} 'o']};
            end
            
            
            %app.g1=find(strcmp(app.column,{[app.column{s} 'smooth']}));
            
            h_=findobj('Tag','edit1LB'); set(h_,'String',app.column);
            h_=findobj('Tag','edit2LB'); set(h_,'String',app.column);
            h_=findobj('Tag','edit3LB'); set(h_,'String',app.column);


            guidata(hObject, handles);
            
            plotta_ora(handles);
        end

        % Button pushed function: trigger
        function trigger_Callback(app, event)
            % hObject    handle to trigger (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            hOb=findobj('Tag','XLab');
            h_ele=get(hOb,'Value');
            
            if h_ele==2
                t_cut=app.Time/1000;
            elseif h_ele==3;
                if any(strcmp(fieldnames(handles),'slip'))
                    t_cut=app.slip;
                elseif any(strcmp(fieldnames(handles),'Slip_Enc_2'))
                    t_cut=app.Slip_Enc_2;
                end
            elseif h_ele==1
                t_cut=app.XLab;
            end
            
            
            A=find(strcmp(fieldnames(handles),'shearT'));
            if isempty(A)
                [xi,yi]=ginput(1) ;
                mat(1,:)=abs(t_cut-ones(size(t_cut))*xi(1));
                ll(:,1)=find(mat(1,:)==min(mat(1,:)));
            else
                prev_trig=find(app.RateZero==app.shearT);
                k=menu(['trigger is' num2str(app.shearT) '. Is that ok?'],'si','no','man');
            
                if (k==1)
                    ll(:,1)=prev_trig;
                elseif (k==2)
                    [xi,yi]=ginput(1) ;
                    mat(1,:)=abs(t_cut-ones(size(t_cut))*xi(1));
                    ll(:,1)=find(mat(1,:)==min(mat(1,:)));
                elseif (k==3)
                    t_cut=app.XLab;
                    [xi]=input('digit a triggering number here = ') ;
                    mat(1,:)=abs(t_cut-ones(size(t_cut))*xi(1));
                    ll(:,1)=find(mat(1,:)==min(mat(1,:)));
            
                end
            end
            app.triggered=app.RateZero(ll);
            
            %cycle if running in t=0
            %for n=1:length(app.column)
            I(1)=find(strcmp(app.column,'Rate'));
            
            %for n=I
            %eval(['app.' app.column{n} ' =app.' app.column{n} '-app.' app.column{n} '(ll(:,1),:);'])
            %endhandle
            app.Time=app.Time-app.Time(ll(:,1),:);
            
            set(hOb,'Value',2)
            
            
            ax_=get(app.axes1,'Children'); dataY=get(ax_,'YData'); dataX=get(ax_,'XData');
            set(ax_,'XData',app.Time,'YData',dataY); set(app.axes1,'XLim',[app.Time(1) app.Time(end)]);
            ax_=get(app.axes2,'Children'); dataY=get(ax_,'YData'); dataX=get(ax_,'XData');
            set(ax_,'XData',app.Time,'YData',dataY); set(app.axes1,'XLim',[app.Time(1) app.Time(end)]);
            ax_=get(app.axes3,'Children'); dataY=get(ax_,'YData'); dataX=get(ax_,'XData');
            set(ax_,'XData',app.Time,'YData',dataY); set(app.axes1,'XLim',[app.Time(1) app.Time(end)]);
            
            guidata(hObject, handles);
            plotta_ora(handles)
        end

        % Value changed function: vac
        function vac_Callback(app, event)
            % hObject    handle to vac (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of vac
        end

        % Menu selected function: write
        function write_Callback(app, event)
            % hObject    handle to write (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [nome,pat]=uiputfile( ...
                {'*.txt', 'All MATLAB Files (*txt)'; ...
                '*.*',                   'All Files (*.*)'}, ...
                'Write as',['~/',app.filename]);
            cd (pat)
            
            
            h_=findobj('Tag','fluid');
            statoF=get(h_,'Value');
            h_=findobj('Tag','GH');
            statoGH=get(h_,'Value');
            
            if statoF==1
                I={'Time' 'shear1' 'EffPressure' 'Mu1' 'Pf' 'LVDT_low' 'LVDT_high' 'vel' 'slip' 'TempE' 'TempM'};
            elseif statoGH==1
                I={'Time' 'shear1' 'Normal' 'Mu1' 'dspring' 'LVDT_low' 'vel' 'slip' 'TempE' 'TempM'};
            else
                I={'Time' 'shear1' 'Normal' 'Mu1' 'LVDT_low' 'vel' 'slip' 'TempE'};
            end
            
            for j=1:length(I); %1:length(app.column)
                C(j,1)={'%10.6f '};
                if j==length(I)
                    C(j,1)={'%10.6f\n'};
                end
            end
            C1=cell2mat(C');
            
            for j=1:length(I); %1:length(app.column)
                N(j,1)={['app.' I{j} '(l,1),']};
                if j==length(I)
                    N(j,1)={['app.' I{j} '(l,1)']};
                end
            end
            N1=cell2mat(N');
            
            
            for j=1:length(I); %1:length(app.column)
                M(j,1)={['''' I{j} '''' ',']};
                if j==length(I)
                    M(j,1)={['''' I{j} '''']};
                end
            end
            M1=cell2mat(M');
            
            %for j=1:length(app.column)
            %    O(j,1)={['''v' num2str(j) '''' ',']};
            %    if j==length(app.column)
            %         O(j,1)={['''v' num2str(j) '''']};
            %    end
            %end
            %O1=cell2mat(O');
            
            
            for j=1:length(I); %1:length(app.column)
                S(j,1)={'%s '};
                if j==length(I)
                    S(j,1)={'%s\n'};
                end
            end
            S1=cell2mat(S');
            
            %write in a file
            nome2=[nome, 'RED.txt'];
            fid = fopen(nome2,'wt');
            eval(['fprintf(fid,''' S1 ''',' M1 ');'])
            
            eval(['len=length(app.' app.column{1} ');'])
            
            for l=1:len
                eval(['fprintf(fid,''' C1 ''',' N1 ');'])
            end
            fclose(fid);
            if ~ strcmp(fieldnames(handles),'dt'); msgbox(['ATTENTION: app.dt=none']); end
        end

        % Value changed function: zero_thickness_long
        function zero_thickness_long_Callback(app, event)
            % hObject    handle to zero_thickness_long (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of zero_thickness_long as text
            %        str2double(get(hObject,'String')) returns contents of zero_thickness_long as a double
        end

        % Value changed function: zero_thickness_short
        function zero_thickness_short_Callback(app, event)
            % hObject    handle to zero_thickness_short (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of zero_thickness_short as text
            %        str2double(get(hObject,'String')) returns contents of zero_thickness_short as a double
        end

        % Value changed function: zoom
        function zoom_Callback(app, event)
            % hObject    handle to zoom (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            h_ele=get(hObject,'Value');
            pippo=[app.axes1, app.axes2,app.axes3];
            linkaxes(pippo,'x');
            
            
            if h_ele==1
                zoom on;
            else
                zoom off
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'shivaUNIX';
            app.File = uimenu(app.figure1);
            app.File.Text = 'File';
            app.OpenMenuItem = uimenu(app.File);
            app.OpenMenuItem.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuItem_Callback, true);
            app.OpenMenuItem.Text = 'Open ...';
            app.OpenMenuItem2 = uimenu(app.File);
            app.OpenMenuItem2.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuItem2_Callback, true);
            app.OpenMenuItem2.Text = 'Open 1710-->';
            app.CloseMenuItem = uimenu(app.File);
            app.CloseMenuItem.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuItem_Callback, true);
            app.CloseMenuItem.Separator = 'on';
            app.CloseMenuItem.Text = 'Close';
            app.save = uimenu(app.File);
            app.save.MenuSelectedFcn = createCallbackFcn(app, @save_Callback, true);
            app.save.Text = 'save(as .mat)';
            app.write = uimenu(app.File);
            app.write.MenuSelectedFcn = createCallbackFcn(app, @write_Callback, true);
            app.write.Text = 'write(as .txt)';
            app.Load = uimenu(app.File);
            app.Load.MenuSelectedFcn = createCallbackFcn(app, @Load_Callback, true);
            app.Load.Text = 'Load';
            app.binary = uimenu(app.File);
            app.binary.MenuSelectedFcn = createCallbackFcn(app, @binary_Callback, true);
            app.binary.Text = 'save to bin';
            app.saveRED = uimenu(app.File);
            app.saveRED.MenuSelectedFcn = createCallbackFcn(app, @saveRED_Callback, true);
            app.saveRED.Text = 'save reduced';
            app.Interactive = uimenu(app.figure1);
            app.Interactive.MenuSelectedFcn = createCallbackFcn(app, @Interactive_Callback, true);
            app.Interactive.Text = 'Interactive';
            app.Figure = uimenu(app.figure1);
            app.Figure.Text = 'Figure';
            app.print = uimenu(app.Figure);
            app.print.MenuSelectedFcn = createCallbackFcn(app, @print_Callback, true);
            app.print.Text = 'print';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.XLim = [0 1];
            app.axes1.YLim = [0 1];
            app.axes1.ZLim = [0 1];
            app.axes1.CLim = [0 1];
            app.axes1.ALim = [0 1];
            app.axes1.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes1.XTickLabel = {'0  '; '0.2'; '0.4'; '0.6'; '0.8'; '1  '};
            app.axes1.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes1.YTickLabel = {'0  '; '0.2'; '0.4'; '0.6'; '0.8'; '1  '};
            app.axes1.ZTick = [0 0.5 1];
            app.axes1.ZTickLabel = '';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.XLim = [0 1];
            app.axes2.YLim = [0 1];
            app.axes2.ZLim = [0 1];
            app.axes2.CLim = [0 1];
            app.axes2.ALim = [0 1];
            app.axes2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes2.XTickLabel = {'0  '; '0.2'; '0.4'; '0.6'; '0.8'; '1  '};
            app.axes2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes2.YTickLabel = {'0  '; '0.2'; '0.4'; '0.6'; '0.8'; '1  '};
            app.axes2.ZTick = [0 0.5 1];
            app.axes2.ZTickLabel = '';
            app.axes3 = uiaxes(app.figure1);
            app.axes3.XLim = [0 1];
            app.axes3.YLim = [0 1];
            app.axes3.ZLim = [0 1];
            app.axes3.CLim = [0 1];
            app.axes3.ALim = [0 1];
            app.axes3.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes3.XTickLabel = {'0  '; '0.2'; '0.4'; '0.6'; '0.8'; '1  '};
            app.axes3.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes3.YTickLabel = {'0  '; '0.2'; '0.4'; '0.6'; '0.8'; '1  '};
            app.axes3.ZTick = [0 0.5 1];
            app.axes3.ZTickLabel = '';
            app.axes4 = uiaxes(app.figure1);
            app.axes5 = uiaxes(app.figure1);
            app.axes6 = uiaxes(app.figure1);
            app.zoom = uibutton(app.figure1, 'state');
            app.zoom.ValueChangedFcn = createCallbackFcn(app, @zoom_Callback, true);
            app.zoom.Text = 'zoom';
            app.pan = uibutton(app.figure1, 'state');
            app.pan.ValueChangedFcn = createCallbackFcn(app, @pan_Callback, true);
            app.pan.Text = 'pan';
            app.edit1 = uieditfield(app.figure1, 'text');
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1_Callback, true);
            app.edit1.Value = '2';
            app.edit2 = uieditfield(app.figure1, 'text');
            app.edit2.ValueChangedFcn = createCallbackFcn(app, @edit2_Callback, true);
            app.edit2.Value = '3';
            app.edit3 = uieditfield(app.figure1, 'text');
            app.edit3.ValueChangedFcn = createCallbackFcn(app, @edit3_Callback, true);
            app.edit3.Value = '5';
            app.XLab = uilistbox(app.figure1);
            app.XLab.Items = {'Rate (#)', 'Time (s)', 'Slip (m)'};
            app.XLab.ValueChangedFcn = createCallbackFcn(app, @XLab_Callback, true);
            app.XLab.Value = 'Rate (#)';
            app.offset = uibutton(app.figure1, 'push');
            app.offset.ButtonPushedFcn = createCallbackFcn(app, @offset_Callback, true);
            app.offset.Text = 'offset';
            app.trigger = uibutton(app.figure1, 'push');
            app.trigger.ButtonPushedFcn = createCallbackFcn(app, @trigger_Callback, true);
            app.trigger.Text = 'trigger';
            app.decimate = uieditfield(app.figure1, 'text');
            app.decimate.ValueChangedFcn = createCallbackFcn(app, @decimate_Callback, true);
            app.decimate.Value = 'decimate';
            app.smooth = uieditfield(app.figure1, 'text');
            app.smooth.ValueChangedFcn = createCallbackFcn(app, @smooth_Callback, true);
            app.smooth.Value = 'smooth';
            app.cut_dt = uieditfield(app.figure1, 'text');
            app.cut_dt.ValueChangedFcn = createCallbackFcn(app, @cut_dt_Callback, true);
            app.cut_dt.Value = 'cut_dt';
            app.cut = uibutton(app.figure1, 'push');
            app.cut.ButtonPushedFcn = createCallbackFcn(app, @cut_Callback, true);
            app.cut.Text = 'cut';
            app.fft = uibutton(app.figure1, 'push');
            app.fft.ButtonPushedFcn = createCallbackFcn(app, @fft_Callback, true);
            app.fft.Text = 'fft';
            app.Rint = uieditfield(app.figure1, 'text');
            app.Rint.Value = '30';
            app.Rext = uieditfield(app.figure1, 'text');
            app.Rext.ValueChangedFcn = createCallbackFcn(app, @Rext_Callback, true);
            app.Rext.Value = '50';
            app.Gefran = uicheckbox(app.figure1);
            app.Gefran.ValueChangedFcn = createCallbackFcn(app, @Gefran_Callback, true);
            app.Gefran.Text = 'Gefran';
            app.running = uibutton(app.figure1, 'push');
            app.running.ButtonPushedFcn = createCallbackFcn(app, @running_Callback, true);
            app.running.Text = 'running mean';
            app.fluid = uicheckbox(app.figure1);
            app.fluid.ValueChangedFcn = createCallbackFcn(app, @fluid_Callback, true);
            app.fluid.Text = 'Fluid Pressure';
            app.outlayers = uibutton(app.figure1, 'push');
            app.outlayers.ButtonPushedFcn = createCallbackFcn(app, @outlayers_Callback, true);
            app.outlayers.Text = 'outlayers';
            app.GH = uicheckbox(app.figure1);
            app.GH.ValueChangedFcn = createCallbackFcn(app, @GH_Callback, true);
            app.GH.Text = 'GougeHolder';
            app.TC = uicheckbox(app.figure1);
            app.TC.ValueChangedFcn = createCallbackFcn(app, @TC_Callback, true);
            app.TC.Text = 'ThermoCouple';
            app.calibration = uidropdown(app.figure1);
            app.calibration.Items = {'calibration', '2) s0001 -- s0035', '3) s0036 -- s0236', '4) s0237 -- s0253', '5) s0254 -- s0261', '6) s0262 -- s1178', '7) s1179 -- s1231; 1.2', '8) s1232 -- s1336; 1.25', '9) s1337; 2', '10) s1338 -- s1667; 1, fc=1.25', '11) s1668 -- s1709; 1, fc =1.25', '12) s1710 -->'};
            app.calibration.Value = 'calibration';
            app.T0 = uieditfield(app.figure1, 'text');
            app.T0.ValueChangedFcn = createCallbackFcn(app, @T0_Callback, true);
            app.T0.Value = '23';
            app.AdjRate = uicheckbox(app.figure1);
            app.AdjRate.ValueChangedFcn = createCallbackFcn(app, @AdjRate_Callback, true);
            app.AdjRate.Text = 'AdjRate';
            app.text5 = uilabel(app.figure1);
            app.text5.Text = 'ext (mm)';
            app.text6 = uilabel(app.figure1);
            app.text6.Text = 'int (mm)';
            app.Torque = uicheckbox(app.figure1);
            app.Torque.ValueChangedFcn = createCallbackFcn(app, @Torque_Callback, true);
            app.Torque.Text = 'Torque Ctrl';
            app.off_enc_0 = uibutton(app.figure1, 'push');
            app.off_enc_0.ButtonPushedFcn = createCallbackFcn(app, @off_enc_0_Callback, true);
            app.off_enc_0.Text = 'offset Enc 0';
            app.incremental = uibutton(app.figure1, 'push');
            app.incremental.Text = 'incremental';
            app.vac = uicheckbox(app.figure1);
            app.vac.ValueChangedFcn = createCallbackFcn(app, @vac_Callback, true);
            app.vac.Text = 'Vacuum';
            app.get_thickness = uicheckbox(app.figure1);
            app.get_thickness.ValueChangedFcn = createCallbackFcn(app, @get_thickness_Callback, true);
            app.get_thickness.Tooltip = '1) Get thickness from LVDT_long specifying the zero position (V) and 2) get thickness subtracting LVDT_short from the initial thickness (mm).';
            app.get_thickness.Text = 'Thickness';
            app.zero_thickness_long = uieditfield(app.figure1, 'text');
            app.zero_thickness_long.ValueChangedFcn = createCallbackFcn(app, @zero_thickness_long_Callback, true);
            app.zero_thickness_long.Tooltip = 'Zero position of LVDT long (V)';
            app.zero_thickness_long.Value = '0';
            app.popupAI6 = uidropdown(app.figure1);
            app.popupAI6.Items = {'AI6', '2) Cosino', '3) TC', '4) Vacuum', '5) Isco PA/PB', '6) Isco VA/VB', '7) Isco FA/FB', '8) RH', '9) TA', '10) Other'};
            app.popupAI6.ValueChangedFcn = createCallbackFcn(app, @popupAI6_Callback, true);
            app.popupAI6.Value = 'AI6';
            app.popupAI7 = uidropdown(app.figure1);
            app.popupAI7.Items = {'AI7', '2) Cosino', '3) TC', '4) Vacuum', '5) Isco PA/PB', '6) Isco VA/VB', '7) Isco FA/FB', '8) RH', '9) TA', '10) Other'};
            app.popupAI7.ValueChangedFcn = createCallbackFcn(app, @popupAI7_Callback, true);
            app.popupAI7.Value = 'AI7';
            app.popupAI8 = uidropdown(app.figure1);
            app.popupAI8.Items = {'AI8', '2) Cosino', '3) TC', '4) Vacuum', '5) Isco PA/PB', '6) Isco VA/VB', '7) Isco FA/FB', '8) RH', '9) TA', '10) Other'};
            app.popupAI8.ValueChangedFcn = createCallbackFcn(app, @popupAI8_Callback, true);
            app.popupAI8.Value = 'AI8';
            app.popupAI9 = uidropdown(app.figure1);
            app.popupAI9.Items = {'AI9', '2) Cosino', '3) TC', '4) Vacuum', '5) Isco PA/PB', '6) Isco VA/VB', '7) Isco FA/FB', '8) RH', '9) TA', '10) Other'};
            app.popupAI9.ValueChangedFcn = createCallbackFcn(app, @popupAI9_Callback, true);
            app.popupAI9.Value = 'AI9';
            app.popupAI10 = uidropdown(app.figure1);
            app.popupAI10.Items = {'AI10', '2) Cosino', '3) TC', '4) Vacuum', '5) Isco PA/PB', '6) Isco VA/VB', '7) Isco FA/FB', '8) RH', '9) TA', '10) Other'};
            app.popupAI10.ValueChangedFcn = createCallbackFcn(app, @popupAI10_Callback, true);
            app.popupAI10.Value = 'AI10';
            app.popupAI16 = uidropdown(app.figure1);
            app.popupAI16.Items = {'AI16', '2) Cosino', '3) TC', '4) Vacuum', '5) Isco PA/PB', '6) Isco VA/VB', '7) Isco FA/FB', '8) RH', '9) TA', '10) Other'};
            app.popupAI16.ValueChangedFcn = createCallbackFcn(app, @popupAI16_Callback, true);
            app.popupAI16.Value = 'AI16';
            app.popupAI17 = uidropdown(app.figure1);
            app.popupAI17.Items = {'AI17', '2) Cosino', '3) TC', '4) Vacuum', '5) Isco PA/PB', '6) Isco VA/VB', '7) Isco FA/FB', '8) RH', '9) TA', '10) Other'};
            app.popupAI17.ValueChangedFcn = createCallbackFcn(app, @popupAI17_Callback, true);
            app.popupAI17.Value = 'AI17';
            app.popupPF = uidropdown(app.figure1);
            app.popupPF.Items = {'PoreFluid', '2) Gefran', '3) Gems', '4) Isco PA/PB'};
            app.popupPF.ValueChangedFcn = createCallbackFcn(app, @popupPF_Callback, true);
            app.popupPF.Value = 'PoreFluid';
            app.popupAI18 = uidropdown(app.figure1);
            app.popupAI18.Items = {'AI18', '2) Cosino', '3) TC', '4) Vacuum', '5) Isco PA/PB', '6) Isco VA/VB', '7) Isco FA/FB', '8) RH', '9) TA', '10) Other'};
            app.popupAI18.ValueChangedFcn = createCallbackFcn(app, @popupAI18_Callback, true);
            app.popupAI18.Value = 'AI18';
            app.popupPC = uidropdown(app.figure1);
            app.popupPC.Items = {'ConfiningPressure', '2) Gefran', '3) Gems', '4) Isco PA/PB'};
            app.popupPC.ValueChangedFcn = createCallbackFcn(app, @popupPC_Callback, true);
            app.popupPC.Value = 'ConfiningPressure';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Nodes';
            app.nodeEnc2 = uieditfield(app.uipanel1, 'text');
            app.nodeEnc2.ValueChangedFcn = createCallbackFcn(app, @nodeEnc2_Callback, true);
            app.nodeEnc2.Value = '40, 0.9';
            app.nodeEnc1 = uieditfield(app.uipanel1, 'text');
            app.nodeEnc1.ValueChangedFcn = createCallbackFcn(app, @nodeEnc1_Callback, true);
            app.nodeEnc1.Value = '80, 0.9';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Text = '2';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Text = '1';
            app.text13 = uilabel(app.figure1);
            app.text13.Text = 'SHIVAunix branch';
            app.pushbutton18 = uibutton(app.figure1, 'push');
            app.pushbutton18.ButtonPushedFcn = createCallbackFcn(app, @pushbutton18_Callback, true);
            app.pushbutton18.Text = 'refreshT';
            app.pushbutton19 = uibutton(app.figure1, 'push');
            app.pushbutton19.ButtonPushedFcn = createCallbackFcn(app, @pushbutton19_Callback, true);
            app.pushbutton19.Text = 'refresh_tau';
            app.pushbutton20 = uibutton(app.figure1, 'push');
            app.pushbutton20.ButtonPushedFcn = createCallbackFcn(app, @pushbutton20_Callback, true);
            app.pushbutton20.Text = 'calibrate';
            app.brutalfilt = uieditfield(app.figure1, 'text');
            app.brutalfilt.ValueChangedFcn = createCallbackFcn(app, @brutalfilt_Callback, true);
            app.brutalfilt.Value = 'brutalfilt';
            app.zero_thickness_short = uieditfield(app.figure1, 'text');
            app.zero_thickness_short.ValueChangedFcn = createCallbackFcn(app, @zero_thickness_short_Callback, true);
            app.zero_thickness_short.Tooltip = 'Initial thickness of the gouge layer (mm).';
            app.zero_thickness_short.Value = '0';
            app.text14 = uilabel(app.figure1);
            app.text14.Text = 'zero (V)';
            app.text15 = uilabel(app.figure1);
            app.text15.Text = '(mm)';
            app.refresh_thickness = uibutton(app.figure1, 'push');
            app.refresh_thickness.ButtonPushedFcn = createCallbackFcn(app, @refresh_thickness_Callback, true);
            app.refresh_thickness.Text = 'refresh_thickness';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = shivaUNIX_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @shivaUNIX_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end